classdef currentinjSuite < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        IndividualNeuronssquarepulseTab  matlab.ui.container.Tab
        InitialParamsLabel              matlab.ui.control.Label
        CurrentInjectionAnalysesLabel   matlab.ui.control.Label
        PulsestartmsEditFieldLabel      matlab.ui.control.Label
        PulsestartmsEditField           matlab.ui.control.NumericEditField
        PulseendmsEditFieldLabel        matlab.ui.control.Label
        PulseendmsEditField             matlab.ui.control.NumericEditField
        BaselinedurationmsEditFieldLabel  matlab.ui.control.Label
        BaselinedurationmsEditField     matlab.ui.control.NumericEditField
        NumberofrunsDropDownLabel       matlab.ui.control.Label
        NumberofrunsDropDown            matlab.ui.control.DropDown
        StartinginjectionpAEditFieldLabel  matlab.ui.control.Label
        StartinginjectionpAEditField    matlab.ui.control.NumericEditField
        InjectiondeltapAEditFieldLabel  matlab.ui.control.Label
        InjectiondeltapAEditField       matlab.ui.control.NumericEditField
        NumberofsweepsEditFieldLabel    matlab.ui.control.Label
        NumberofsweepsEditField         matlab.ui.control.NumericEditField
        Run1Label                       matlab.ui.control.Label
        Run2Label                       matlab.ui.control.Label
        StartinginjectionpAEditField_2Label  matlab.ui.control.Label
        StartinginjectionpAEditField_2  matlab.ui.control.NumericEditField
        InjectiondeltapAEditField_2Label  matlab.ui.control.Label
        InjectiondeltapAEditField_2     matlab.ui.control.NumericEditField
        NumberofsweepsEditField_2Label  matlab.ui.control.Label
        NumberofsweepsEditField_2       matlab.ui.control.NumericEditField
        IndividualNeuronsramppulseTab   matlab.ui.container.Tab
        InitialParamsLabel_2            matlab.ui.control.Label
        CurrentInjectionAnalysesLabel_3  matlab.ui.control.Label
        RampstartmsEditFieldLabel       matlab.ui.control.Label
        RampstartmsEditField            matlab.ui.control.NumericEditField
        BaselinedurationmsEditField_2Label  matlab.ui.control.Label
        BaselinedurationmsEditField_2   matlab.ui.control.NumericEditField
        GroupComparisonsTab             matlab.ui.container.Tab
        CurrentInjectionAnalysesLabel_2  matlab.ui.control.Label
        GrponeplotcolorDropDown         matlab.ui.control.DropDown
        GrponeplotcolorDropDownLabel    matlab.ui.control.Label
        ExpgrpthreeCheckBox             matlab.ui.control.CheckBox
        Grp3EditFieldLabel              matlab.ui.control.Label
        Grp3EditField                   matlab.ui.control.EditField
        ExCellEditFieldLabel            matlab.ui.control.Label
        ExCellEditField                 matlab.ui.control.NumericEditField
        ExpgrptwoCheckBox               matlab.ui.control.CheckBox
        Grp2EditFieldLabel              matlab.ui.control.Label
        Grp2EditField                   matlab.ui.control.EditField
        ExCellEditField_2Label          matlab.ui.control.Label
        ExCellEditField_2               matlab.ui.control.NumericEditField
        ExpgrponeCheckBox               matlab.ui.control.CheckBox
        Grp1EditFieldLabel              matlab.ui.control.Label
        Grp1EditField                   matlab.ui.control.EditField
        ExCellEditField_3Label          matlab.ui.control.Label
        ExCellEditField_3               matlab.ui.control.NumericEditField
        GrptwoplotcolorDropDown         matlab.ui.control.DropDown
        GrptwoplotcolorDropDownLabel    matlab.ui.control.Label
        GrpthreeplotcolorDropDown       matlab.ui.control.DropDown
        GrpthreeplotcolorDropDownLabel  matlab.ui.control.Label
        FolderNameEditFieldLabel        matlab.ui.control.Label
        FolderNameEditField             matlab.ui.control.EditField
        SquareinjectionprotocolCheckBox  matlab.ui.control.CheckBox
        RampinjectionprotocolCheckBox   matlab.ui.control.CheckBox
        ExCellEditField_4               matlab.ui.control.NumericEditField
        ExCellEditField_5               matlab.ui.control.NumericEditField
        ExCellEditField_6               matlab.ui.control.NumericEditField
        PickFolderButton                matlab.ui.control.Button
        RunButton                       matlab.ui.control.Button
        FolderTBDLabel                  matlab.ui.control.Label
        SaveButton                      matlab.ui.control.Button
        PlotcolorDropDownLabel          matlab.ui.control.Label
        PlotcolorDropDown               matlab.ui.control.DropDown
        SamplerateHzEditFieldLabel      matlab.ui.control.Label
        SamplerateHzEditField           matlab.ui.control.NumericEditField
    end

    
    properties (Access = public)
        ciDir % main directory for cell
        fldrs % folders for each isi
        ciData % square pulse  data
        rampData % ramp pulse data
        ciCompData % grp comparison data
        plotColor %color for plotting
        plotColorGrps %color for grp comparisons
    end
    

    methods (Access = private)

        % Button pushed function: PickFolderButton
        function PickFolderButtonPushed(app, event)
            clear app.ciDir app.fldrs fIO
            
            if strcmp(app.TabGroup.SelectedTab.Title,'Individual Neurons, square pulse')
                app.ciDir=uigetdir;
                cd(app.ciDir);
                contents = dir;
                fPP = {contents.name}';
                app.fldrs = cell(1,app.NumberofrunsDropDown.Value);
                for ii = 1:str2double(app.NumberofrunsDropDown.Value)
                    app.fldrs{ii} = fPP{end-str2double(app.NumberofrunsDropDown.Value)+ii};
                end
                thisPt = find(app.ciDir=='/');
                app.FolderTBDLabel.Text = app.ciDir(thisPt(end)+1:end);
            elseif strcmp(app.TabGroup.SelectedTab.Title,'Individual Neurons, ramp pulse')
                app.ciDir=uigetdir;
                cd(app.ciDir);
                contents = dir;
                fPP = {contents.name}';
                app.fldrs = cell(1,app.NumberofrunsDropDown.Value);
                for ii = 1:str2double(app.NumberofrunsDropDown.Value)
                    app.fldrs{ii} = fPP{end-str2double(app.NumberofrunsDropDown.Value)+ii};
                end
                thisPt = find(app.ciDir=='/');
                app.FolderTBDLabel.Text = app.ciDir(thisPt(end)+1:end);
            elseif strcmp(app.TabGroup.SelectedTab.Title,'Group Comparisons')
                app.ciDir=uigetdir;
                thisPt = find(app.ciDir=='/');
                app.FolderTBDLabel.Text = app.ciDir(thisPt(end)+1:end);
            end
        end

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
            clear app.ciData
            
            
            if strcmp(app.TabGroup.SelectedTab.Title,'Individual Neurons, square pulse')
                
                %get color
                app.plotColor = zeros(1,3);
                if strcmp(app.PlotcolorDropDown.Value,'Black')
                    app.plotColor = [0 0 0];
                elseif strcmp(app.PlotcolorDropDown.Value,'Blue')
                    app.plotColor = .5.*[.3333 .8039 .9882];
                elseif strcmp(app.PlotcolorDropDown.Value,'Red')
                    app.plotColor = [.3922 0 0];
                elseif strcmp(app.PlotcolorDropDown.Value,'Green')
                    app.plotColor = [.35 .6 .25];
                elseif strcmp(app.PlotcolorDropDown.Value,'Orange')
                    app.plotColor = [.85 .35 .1];
                elseif strcmp(app.PlotcolorDropDown.Value,'Purple')
                    app.plotColor = [.5 .33 .55];
                elseif strcmp(app.PlotcolorDropDown.Value,'Pink')
                    app.plotColor = [.9686 .6588 .7216]./1.5;
                end
                
                %get data
                close all
                app.ciData = currentinjFxn(app);
            elseif strcmp(app.TabGroup.SelectedTab.Title,'Individual Neurons, ramp pulse')
                
                %get color
                app.plotColor = zeros(1,3);
                if strcmp(app.PlotcolorDropDown.Value,'Black')
                    app.plotColor = [0 0 0];
                elseif strcmp(app.PlotcolorDropDown.Value,'Blue')
                    app.plotColor = .9.*[.3333 .8039 .9882];
                elseif strcmp(app.PlotcolorDropDown.Value,'Red')
                    app.plotColor = [.3922 0 0];
                elseif strcmp(app.PlotcolorDropDown.Value,'Green')
                    app.plotColor = [.35 .6 .25];
                elseif strcmp(app.PlotcolorDropDown.Value,'Orange')
                    app.plotColor = [.85 .35 .1];
                elseif strcmp(app.PlotcolorDropDown.Value,'Purple')
                    app.plotColor = [.5 .33 .55];
                elseif strcmp(app.PlotcolorDropDown.Value,'Pink')
                    app.plotColor = [.9686 .6588 .7216];
                end
                
                %get data
                close all
                app.rampData = rampinjFxn(app);
                
            elseif strcmp(app.TabGroup.SelectedTab.Title,'Group Comparisons')
                
                % get color
                app.plotColorGrps = zeros(3,3);
                if strcmp(app.GrponeplotcolorDropDown.Value,'Black')
                    app.plotColorGrps(1,:) = [0 0 0];
                elseif strcmp(app.GrponeplotcolorDropDown.Value,'Blue')
                    app.plotColorGrps(1,:) = [.3333 .8039 .9882];
                elseif strcmp(app.GrponeplotcolorDropDown.Value,'Red')
                    app.plotColorGrps(1,:) = [.3922 0 0];
                elseif strcmp(app.GrponeplotcolorDropDown.Value,'Green')
                    app.plotColorGrps(1,:) = [.35 .6 .25];
                elseif strcmp(app.GrponeplotcolorDropDown.Value,'Orange')
                    app.plotColorGrps(1,:) = [.85 .35 .1];
                elseif strcmp(app.GrponeplotcolorDropDown.Value,'Purple')
                    app.plotColorGrps(1,:) = [.6 .4 .67];
                elseif strcmp(app.GrponeplotcolorDropDown.Value,'Pink')
                    app.plotColorGrps(1,:) = [.9686 .6588 .7216];
                end
                
                if strcmp(app.GrptwoplotcolorDropDown.Value,'Black')
                    app.plotColorGrps(2,:) = [0 0 0];
                elseif strcmp(app.GrptwoplotcolorDropDown.Value,'Blue')
                    app.plotColorGrps(2,:) = [.3333 .8039 .9882];
                elseif strcmp(app.GrptwoplotcolorDropDown.Value,'Red')
                    app.plotColorGrps(2,:) = [.3922 0 0];
                elseif strcmp(app.GrptwoplotcolorDropDown.Value,'Green')
                    app.plotColorGrps(2,:) = [.35 .6 .25];
                elseif strcmp(app.GrptwoplotcolorDropDown.Value,'Orange')
                    app.plotColorGrps(2,:) = [.85 .35 .1];
                elseif strcmp(app.GrptwoplotcolorDropDown.Value,'Purple')
                    app.plotColorGrps(2,:) = [.6 .4 .67];
                elseif strcmp(app.GrptwoplotcolorDropDown.Value,'Pink')
                    app.plotColorGrps(2,:) = [.9686 .6588 .7216];
                end
                
                if strcmp(app.GrpthreeplotcolorDropDown.Value,'Black')
                    app.plotColorGrps(3,:) = [0 0 0];
                elseif strcmp(app.GrpthreeplotcolorDropDown.Value,'Blue')
                    app.plotColorGrps(3,:) = [.3333 .8039 .9882];
                elseif strcmp(app.GrpthreeplotcolorDropDown.Value,'Red')
                    app.plotColorGrps(3,:) = [.3922 0 0];
                elseif strcmp(app.GrpthreeplotcolorDropDown.Value,'Green')
                    app.plotColorGrps(3,:) = [.35 .6 .25];
                elseif strcmp(app.GrpthreeplotcolorDropDown.Value,'Orange')
                    app.plotColorGrps(3,:) = [.85 .35 .1];
                elseif strcmp(app.GrpthreeplotcolorDropDown.Value,'Purple')
                    app.plotColorGrps(3,:) = [.6 .4 .67];
                elseif strcmp(app.GrpthreeplotcolorDropDown.Value,'Pink')
                    app.plotColorGrps(3,:) = [.9686 .6588 .7216];
                end
                
                app.ciCompData = ciCompFxn(app);
            end
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            clear save_file path_save
            [save_file,path_save]=uiputfile;
            full_save_file=fullfile(path_save,save_file);
            save(full_save_file);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.IndividualNeuronssquarepulseTab = uitab(app.TabGroup);
            app.IndividualNeuronssquarepulseTab.Title = 'Individual Neurons, square pulse';
            app.InitialParamsLabel = uilabel(app.IndividualNeuronssquarepulseTab);
            app.InitialParamsLabel.Text = 'Initial Params';
            app.CurrentInjectionAnalysesLabel = uilabel(app.IndividualNeuronssquarepulseTab);
            app.CurrentInjectionAnalysesLabel.Text = 'Current Injection Analyses';
            app.PulsestartmsEditFieldLabel = uilabel(app.IndividualNeuronssquarepulseTab);
            app.PulsestartmsEditFieldLabel.Text = 'Pulse start (ms)';
            app.PulsestartmsEditField = uieditfield(app.IndividualNeuronssquarepulseTab, 'numeric');
            app.PulsestartmsEditField.Limits = [0 Inf];
            app.PulsestartmsEditField.ValueDisplayFormat = '%11.10g';
            app.PulsestartmsEditField.Value = 546.9;
            app.PulseendmsEditFieldLabel = uilabel(app.IndividualNeuronssquarepulseTab);
            app.PulseendmsEditFieldLabel.Text = 'Pulse end (ms)';
            app.PulseendmsEditField = uieditfield(app.IndividualNeuronssquarepulseTab, 'numeric');
            app.PulseendmsEditField.Limits = [0 Inf];
            app.PulseendmsEditField.ValueDisplayFormat = '%11.10g';
            app.PulseendmsEditField.Value = 1146.8;
            app.BaselinedurationmsEditFieldLabel = uilabel(app.IndividualNeuronssquarepulseTab);
            app.BaselinedurationmsEditFieldLabel.Text = 'Baseline duration (ms)';
            app.BaselinedurationmsEditField = uieditfield(app.IndividualNeuronssquarepulseTab, 'numeric');
            app.BaselinedurationmsEditField.Value = 100;
            app.NumberofrunsDropDownLabel = uilabel(app.IndividualNeuronssquarepulseTab);
            app.NumberofrunsDropDownLabel.Text = 'Number of runs';
            app.NumberofrunsDropDown = uidropdown(app.IndividualNeuronssquarepulseTab);
            app.NumberofrunsDropDown.Items = {'1', '2'};
            app.NumberofrunsDropDown.Value = '2';
            app.StartinginjectionpAEditFieldLabel = uilabel(app.IndividualNeuronssquarepulseTab);
            app.StartinginjectionpAEditFieldLabel.Text = 'Starting injection (pA)';
            app.StartinginjectionpAEditField = uieditfield(app.IndividualNeuronssquarepulseTab, 'numeric');
            app.StartinginjectionpAEditField.ValueDisplayFormat = '%11.10g';
            app.StartinginjectionpAEditField.Value = -100;
            app.InjectiondeltapAEditFieldLabel = uilabel(app.IndividualNeuronssquarepulseTab);
            app.InjectiondeltapAEditFieldLabel.Text = 'Injection delta (pA)';
            app.InjectiondeltapAEditField = uieditfield(app.IndividualNeuronssquarepulseTab, 'numeric');
            app.InjectiondeltapAEditField.ValueDisplayFormat = '%11.10g';
            app.InjectiondeltapAEditField.Value = 10;
            app.NumberofsweepsEditFieldLabel = uilabel(app.IndividualNeuronssquarepulseTab);
            app.NumberofsweepsEditFieldLabel.Text = 'Number of sweeps';
            app.NumberofsweepsEditField = uieditfield(app.IndividualNeuronssquarepulseTab, 'numeric');
            app.NumberofsweepsEditField.Value = 21;
            app.Run1Label = uilabel(app.IndividualNeuronssquarepulseTab);
            app.Run1Label.Text = 'Run 1';
            app.Run2Label = uilabel(app.IndividualNeuronssquarepulseTab);
            app.Run2Label.Text = 'Run 2';
            app.StartinginjectionpAEditField_2Label = uilabel(app.IndividualNeuronssquarepulseTab);
            app.StartinginjectionpAEditField_2Label.Text = 'Starting injection (pA)';
            app.StartinginjectionpAEditField_2 = uieditfield(app.IndividualNeuronssquarepulseTab, 'numeric');
            app.StartinginjectionpAEditField_2.ValueDisplayFormat = '%11.10g';
            app.StartinginjectionpAEditField_2.Value = -200;
            app.InjectiondeltapAEditField_2Label = uilabel(app.IndividualNeuronssquarepulseTab);
            app.InjectiondeltapAEditField_2Label.Text = 'Injection delta (pA)';
            app.InjectiondeltapAEditField_2 = uieditfield(app.IndividualNeuronssquarepulseTab, 'numeric');
            app.InjectiondeltapAEditField_2.ValueDisplayFormat = '%11.10g';
            app.InjectiondeltapAEditField_2.Value = 25;
            app.NumberofsweepsEditField_2Label = uilabel(app.IndividualNeuronssquarepulseTab);
            app.NumberofsweepsEditField_2Label.Text = 'Number of sweeps';
            app.NumberofsweepsEditField_2 = uieditfield(app.IndividualNeuronssquarepulseTab, 'numeric');
            app.NumberofsweepsEditField_2.Value = 25;
            app.IndividualNeuronsramppulseTab = uitab(app.TabGroup);
            app.IndividualNeuronsramppulseTab.Title = 'Individual Neurons, ramp pulse';
            app.InitialParamsLabel_2 = uilabel(app.IndividualNeuronsramppulseTab);
            app.InitialParamsLabel_2.Text = 'Initial Params';
            app.CurrentInjectionAnalysesLabel_3 = uilabel(app.IndividualNeuronsramppulseTab);
            app.CurrentInjectionAnalysesLabel_3.Text = 'Current Injection Analyses';
            app.RampstartmsEditFieldLabel = uilabel(app.IndividualNeuronsramppulseTab);
            app.RampstartmsEditFieldLabel.Text = 'Ramp start (ms)';
            app.RampstartmsEditField = uieditfield(app.IndividualNeuronsramppulseTab, 'numeric');
            app.RampstartmsEditField.Limits = [0 Inf];
            app.RampstartmsEditField.ValueDisplayFormat = '%11.10g';
            app.RampstartmsEditField.Value = 583.2;
            app.BaselinedurationmsEditField_2Label = uilabel(app.IndividualNeuronsramppulseTab);
            app.BaselinedurationmsEditField_2Label.Text = 'Baseline duration (ms)';
            app.BaselinedurationmsEditField_2 = uieditfield(app.IndividualNeuronsramppulseTab, 'numeric');
            app.BaselinedurationmsEditField_2.Value = 500;
            app.GroupComparisonsTab = uitab(app.TabGroup);
            app.GroupComparisonsTab.Title = 'Group Comparisons';
            app.CurrentInjectionAnalysesLabel_2 = uilabel(app.GroupComparisonsTab);
            app.CurrentInjectionAnalysesLabel_2.Text = 'Current Injection Analyses';
            app.GrponeplotcolorDropDown = uidropdown(app.GroupComparisonsTab);
            app.GrponeplotcolorDropDown.Items = {'Black', 'Blue', 'Red', 'Green', 'Orange', 'Purple', 'Pink'};
            app.GrponeplotcolorDropDown.Value = 'Black';
            app.GrponeplotcolorDropDownLabel = uilabel(app.GroupComparisonsTab);
            app.GrponeplotcolorDropDownLabel.Text = 'Grp one plot color';
            app.ExpgrpthreeCheckBox = uicheckbox(app.GroupComparisonsTab);
            app.ExpgrpthreeCheckBox.Text = 'Exp grp three';
            app.Grp3EditFieldLabel = uilabel(app.GroupComparisonsTab);
            app.Grp3EditFieldLabel.Text = 'Grp 3:';
            app.Grp3EditField = uieditfield(app.GroupComparisonsTab, 'text');
            app.ExCellEditFieldLabel = uilabel(app.GroupComparisonsTab);
            app.ExCellEditFieldLabel.Text = 'Ex. Cell';
            app.ExCellEditField = uieditfield(app.GroupComparisonsTab, 'numeric');
            app.ExCellEditField.Value = 1;
            app.ExpgrptwoCheckBox = uicheckbox(app.GroupComparisonsTab);
            app.ExpgrptwoCheckBox.Text = 'Exp grp two';
            app.ExpgrptwoCheckBox.Value = true;
            app.Grp2EditFieldLabel = uilabel(app.GroupComparisonsTab);
            app.Grp2EditFieldLabel.Text = 'Grp 2:';
            app.Grp2EditField = uieditfield(app.GroupComparisonsTab, 'text');
            app.ExCellEditField_2Label = uilabel(app.GroupComparisonsTab);
            app.ExCellEditField_2Label.Text = 'Ex. Cell';
            app.ExCellEditField_2 = uieditfield(app.GroupComparisonsTab, 'numeric');
            app.ExCellEditField_2.Value = 1;
            app.ExpgrponeCheckBox = uicheckbox(app.GroupComparisonsTab);
            app.ExpgrponeCheckBox.Text = 'Exp grp one';
            app.ExpgrponeCheckBox.Value = true;
            app.Grp1EditFieldLabel = uilabel(app.GroupComparisonsTab);
            app.Grp1EditFieldLabel.Text = 'Grp 1:';
            app.Grp1EditField = uieditfield(app.GroupComparisonsTab, 'text');
            app.ExCellEditField_3Label = uilabel(app.GroupComparisonsTab);
            app.ExCellEditField_3Label.Text = 'Ex. Cell';
            app.ExCellEditField_3 = uieditfield(app.GroupComparisonsTab, 'numeric');
            app.ExCellEditField_3.Value = 1;
            app.GrptwoplotcolorDropDown = uidropdown(app.GroupComparisonsTab);
            app.GrptwoplotcolorDropDown.Items = {'Black', 'Blue', 'Red', 'Green', 'Orange', 'Purple', 'Pink'};
            app.GrptwoplotcolorDropDown.Value = 'Black';
            app.GrptwoplotcolorDropDownLabel = uilabel(app.GroupComparisonsTab);
            app.GrptwoplotcolorDropDownLabel.Text = 'Grp two plot color';
            app.GrpthreeplotcolorDropDown = uidropdown(app.GroupComparisonsTab);
            app.GrpthreeplotcolorDropDown.Items = {'Black', 'Blue', 'Red', 'Green', 'Orange', 'Purple', 'Pink'};
            app.GrpthreeplotcolorDropDown.Value = 'Black';
            app.GrpthreeplotcolorDropDownLabel = uilabel(app.GroupComparisonsTab);
            app.GrpthreeplotcolorDropDownLabel.Text = 'Grp three plot color';
            app.FolderNameEditFieldLabel = uilabel(app.GroupComparisonsTab);
            app.FolderNameEditFieldLabel.Text = 'Folder Name';
            app.FolderNameEditField = uieditfield(app.GroupComparisonsTab, 'text');
            app.FolderNameEditField.Value = 'tbd';
            app.SquareinjectionprotocolCheckBox = uicheckbox(app.GroupComparisonsTab);
            app.SquareinjectionprotocolCheckBox.Text = 'Square injection protocol';
            app.RampinjectionprotocolCheckBox = uicheckbox(app.GroupComparisonsTab);
            app.RampinjectionprotocolCheckBox.Text = 'Ramp injection protocol';
            app.ExCellEditField_4 = uieditfield(app.GroupComparisonsTab, 'numeric');
            app.ExCellEditField_4.Value = 1;
            app.ExCellEditField_5 = uieditfield(app.GroupComparisonsTab, 'numeric');
            app.ExCellEditField_5.Value = 1;
            app.ExCellEditField_6 = uieditfield(app.GroupComparisonsTab, 'numeric');
            app.ExCellEditField_6.Value = 1;
            app.PickFolderButton = uibutton(app.UIFigure, 'push');
            app.PickFolderButton.ButtonPushedFcn = createCallbackFcn(app, @PickFolderButtonPushed, true);
            app.PickFolderButton.Text = 'Pick Folder';
            app.RunButton = uibutton(app.UIFigure, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'Run';
            app.FolderTBDLabel = uilabel(app.UIFigure);
            app.FolderTBDLabel.Text = 'Folder TBD';
            app.SaveButton = uibutton(app.UIFigure, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.PlotcolorDropDownLabel = uilabel(app.UIFigure);
            app.PlotcolorDropDownLabel.Text = 'Plot color';
            app.PlotcolorDropDown = uidropdown(app.UIFigure);
            app.PlotcolorDropDown.Items = {'Black', 'Blue', 'Red', 'Green', 'Orange', 'Purple', 'Pink'};
            app.PlotcolorDropDown.Value = 'Black';
            app.SamplerateHzEditFieldLabel = uilabel(app.UIFigure);
            app.SamplerateHzEditFieldLabel.Text = 'Sample rate (Hz)';
            app.SamplerateHzEditField = uieditfield(app.UIFigure, 'numeric');
            app.SamplerateHzEditField.Limits = [0 Inf];
            app.SamplerateHzEditField.ValueDisplayFormat = '%11.10g';
            app.SamplerateHzEditField.Value = 10000;
        end
    end

    methods (Access = public)

        % Construct app
        function app = currentinjSuite

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end