classdef stimSpkSuite < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        TabGroup                       matlab.ui.container.TabGroup
        IndividualNeuronAnalysisTab    matlab.ui.container.Tab
        StimulusEvokedSpikingAnalysisLabel  matlab.ui.control.Label
        InitialParamsLabel             matlab.ui.control.Label
        StimulusstartmsEditFieldLabel  matlab.ui.control.Label
        StimulusstartmsEditField       matlab.ui.control.NumericEditField
        BaselinedurationmsEditFieldLabel  matlab.ui.control.Label
        BaselinedurationmsEditField    matlab.ui.control.NumericEditField
        NostimuliEditFieldLabel        matlab.ui.control.Label
        NostimuliEditField             matlab.ui.control.NumericEditField
        StimulusFreqHzEditFieldLabel   matlab.ui.control.Label
        StimulusFreqHzEditField        matlab.ui.control.NumericEditField
        PlotcolorDropDownLabel         matlab.ui.control.Label
        PlotcolorDropDown              matlab.ui.control.DropDown
        GroupComparisonsTab            matlab.ui.container.Tab
        ExCellEditField_2Label         matlab.ui.control.Label
        ExCellGrp2EditField            matlab.ui.control.NumericEditField
        ExpgrponeCheckBox              matlab.ui.control.CheckBox
        ExpgrptwoCheckBox              matlab.ui.control.CheckBox
        PairedAnalysisCheckBox         matlab.ui.control.CheckBox
        Grp1EditFieldLabel             matlab.ui.control.Label
        Grp1EditField                  matlab.ui.control.EditField
        Grp2EditFieldLabel             matlab.ui.control.Label
        Grp2EditField                  matlab.ui.control.EditField
        ExCellEditField_4Label         matlab.ui.control.Label
        ExCellGrp1EditField            matlab.ui.control.NumericEditField
        StimulusEvokedSpikingAnalysisLabel_2  matlab.ui.control.Label
        InitialParamsLabel_2           matlab.ui.control.Label
        GrouponecolorDropDownLabel     matlab.ui.control.Label
        GrouponecolorDropDown          matlab.ui.control.DropDown
        GrouptwocolorDropDownLabel     matlab.ui.control.Label
        GrouptwocolorDropDown          matlab.ui.control.DropDown
        PickFolderButton               matlab.ui.control.Button
        RunButton                      matlab.ui.control.Button
        FolderTBDLabel                 matlab.ui.control.Label
        SaveButton                     matlab.ui.control.Button
        SamplerateHzEditFieldLabel     matlab.ui.control.Label
        SamplerateHzEditField          matlab.ui.control.NumericEditField
    end

    
    properties (Access = public)
        stimSpkDir %data directory
        stimSpkData %data structure for ind neuron analysis
        stimSpkCompData %data structure for group comparisons
        plotColor %color choice
        plotColorGrpOne %color choice for group one in comparison
        plotColorGrpTwo %color choice for group two in comparison
    end
    

    methods (Access = private)

        % Button pushed function: PickFolderButton
        function PickFolderButtonPushed(app, event)
            clear app.stimSpkDir
            
            if strcmp(app.TabGroup.SelectedTab.Title,'Individual Neuron Analysis')
                app.stimSpkDir=uigetdir;
                cd(app.stimSpkDir);
                
                thisPt = find(app.stimSpkDir=='/');
                app.FolderTBDLabel.Text = app.stimSpkDir(thisPt(end-1)+1:end);
            elseif strcmp(app.TabGroup.SelectedTab.Title,'Group Comparisons')
                app.stimSpkDir=uigetdir;
                thisPt = find(app.stimSpkDir=='/');
                app.FolderTBDLabel.Text = app.stimSpkDir(thisPt(end)+1:end);
            end
        end

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
            clear app.stimSpkData
            
            close all
            
            if strcmp(app.TabGroup.SelectedTab.Title,'Individual Neuron Analysis')
                app.plotColor = zeros(1,3);
            if app.PlotcolorDropDown.Value == 'Black_'
                app.plotColor = [0 0 0];
            elseif app.PlotcolorDropDown.Value == 'Blue__'
                app.plotColor = [.0625 .2545 .433];
            elseif app.PlotcolorDropDown.Value == 'Red___'
                app.plotColor = [.3922 0 0];
            elseif app.PlotcolorDropDown.Value == 'Green_'
                app.plotColor = [.35 .6 .25];
            elseif app.PlotcolorDropDown.Value == 'Orange'
                app.plotColor = [.85 .35 .1];
            elseif app.PlotcolorDropDown.Value == 'Purple'
                app.plotColor = [.5 .33 .55];
            elseif app.PlotcolorDropDown.Value == 'Pink__'
                app.plotColor = [.5 .11 .2];
            end
            
                app.stimSpkData = stimSpkFxn(app);
            elseif strcmp(app.TabGroup.SelectedTab.Title,'Group Comparisons')
                app.plotColorGrpOne = zeros(1,3);
                app.plotColorGrpTwo = zeros(1,3);
                if (app.GrouponecolorDropDown.Value==app.GrouptwocolorDropDown.Value) == 1
                    %color one
                    if app.GrouponecolorDropDown.Value == 'Black_'
                        app.plotColorGrpOne = [0 0 0];
                    elseif app.GrouponecolorDropDown.Value == 'Blue__'
                        app.plotColorGrpOne = [.0625 .2545 .433];
                    elseif app.GrouponecolorDropDown.Value == 'Red___'
                        app.plotColorGrpOne = [.3922 0 0];
                    elseif app.GrouponecolorDropDown.Value == 'Green_'
                        app.plotColorGrpOne = [.35 .6 .25];
                    elseif app.GrouponecolorDropDown.Value == 'Orange'
                        app.plotColorGrpOne = [.85 .35 .1];
                    elseif app.GrouponecolorDropDown.Value == 'Purple'
                        app.plotColorGrpOne = [.5 .33 .55];
                    elseif app.GrouponecolorDropDown.Value == 'Pink__'
                        app.plotColorGrpOne = [.5 .11 .2];
                    end
                    
                    %color two
                    %linearly scales RGB values to a point where max value is .85 for one of R,G,B relative to max value in color 1
                    app.plotColorGrpTwo = (1+(.85-max(app.plotColorGrpOne))./max(app.plotColorGrpOne)).*app.plotColorGrpOne; 
                    
                elseif (app.GrouponecolorDropDown.Value==app.GrouptwocolorDropDown.Value) == 0
                    %color one
                    if app.GrouponecolorDropDown.Value == 'Black_'
                        app.plotColorGrpOne = [0 0 0];
                    elseif app.GrouponecolorDropDown.Value == 'Blue__'
                        app.plotColorGrpOne = [.0625 .2545 .433];
                    elseif app.GrouponecolorDropDown.Value == 'Red___'
                        app.plotColorGrpOne = [.3922 0 0];
                    elseif app.GrouponecolorDropDown.Value == 'Green_'
                        app.plotColorGrpOne = [.35 .6 .25];
                    elseif app.GrouponecolorDropDown.Value == 'Orange'
                        app.plotColorGrpOne = [.85 .35 .1];
                    elseif app.GrouponecolorDropDown.Value == 'Purple'
                        app.plotColorGrpOne = [.5 .33 .55];
                    elseif app.GrouponecolorDropDown.Value == 'Pink__'
                        app.plotColorGrpOne = [.5 .11 .2];
                    end
                    
                    %color two
                    if app.GrouptwocolorDropDown.Value == 'Black_'
                        app.plotColorGrpTwo = [0 0 0];
                    elseif app.GrouptwocolorDropDown.Value == 'Blue__'
                        app.plotColorGrpTwo = [.0625 .2545 .433];
                    elseif app.GrouptwocolorDropDown.Value == 'Red___'
                        app.plotColorGrpTwo = [.3922 0 0];
                    elseif app.GrouptwocolorDropDown.Value == 'Green_'
                        app.plotColorGrpTwo = [.35 .6 .25];
                    elseif app.GrouptwocolorDropDown.Value == 'Orange'
                        app.plotColorGrpTwo = [.85 .35 .1];
                    elseif app.GrouptwocolorDropDown.Value == 'Purple'
                        app.plotColorGrpTwo = [.5 .33 .55];
                    elseif app.GrouptwocolorDropDown.Value == 'Pink__'
                        app.plotColorGrpTwo = [.5 .11 .2];
                    end
                    
                end
                app.stimSpkCompData = stimSpkCompFxn(app);
            end
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            clear save_file path_save
            [save_file,path_save]=uiputfile;
            full_save_file=fullfile(path_save,save_file);
            save(full_save_file);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            setAutoResize(app, app.UIFigure, true)
            app.TabGroup = uitabgroup(app.UIFigure);
            app.IndividualNeuronAnalysisTab = uitab(app.TabGroup);
            app.IndividualNeuronAnalysisTab.Title = 'Individual Neuron Analysis';
            app.IndividualNeuronAnalysisTab.Units = 'pixels';
            app.StimulusEvokedSpikingAnalysisLabel = uilabel(app.IndividualNeuronAnalysisTab);
            app.StimulusEvokedSpikingAnalysisLabel.Text = 'Stimulus Evoked Spiking Analysis';
            app.InitialParamsLabel = uilabel(app.IndividualNeuronAnalysisTab);
            app.InitialParamsLabel.Text = 'Initial Params';
            app.StimulusstartmsEditFieldLabel = uilabel(app.IndividualNeuronAnalysisTab);
            app.StimulusstartmsEditFieldLabel.Text = 'Stimulus start (ms)';
            app.StimulusstartmsEditField = uieditfield(app.IndividualNeuronAnalysisTab, 'numeric');
            app.StimulusstartmsEditField.Limits = [0 Inf];
            app.StimulusstartmsEditField.ValueDisplayFormat = '%11.10g';
            app.StimulusstartmsEditField.Value = 2562.5;
            app.BaselinedurationmsEditFieldLabel = uilabel(app.IndividualNeuronAnalysisTab);
            app.BaselinedurationmsEditFieldLabel.Text = 'Baseline duration (ms)';
            app.BaselinedurationmsEditField = uieditfield(app.IndividualNeuronAnalysisTab, 'numeric');
            app.BaselinedurationmsEditField.Limits = [0 Inf];
            app.BaselinedurationmsEditField.ValueDisplayFormat = '%11.10g';
            app.BaselinedurationmsEditField.Value = 500;
            app.NostimuliEditFieldLabel = uilabel(app.IndividualNeuronAnalysisTab);
            app.NostimuliEditFieldLabel.Text = 'No. stimuli';
            app.NostimuliEditField = uieditfield(app.IndividualNeuronAnalysisTab, 'numeric');
            app.NostimuliEditField.Limits = [0 Inf];
            app.NostimuliEditField.ValueDisplayFormat = '%11.10g';
            app.NostimuliEditField.Value = 5;
            app.StimulusFreqHzEditFieldLabel = uilabel(app.IndividualNeuronAnalysisTab);
            app.StimulusFreqHzEditFieldLabel.Text = 'Stimulus Freq. (Hz)';
            app.StimulusFreqHzEditField = uieditfield(app.IndividualNeuronAnalysisTab, 'numeric');
            app.StimulusFreqHzEditField.Limits = [0 Inf];
            app.StimulusFreqHzEditField.ValueDisplayFormat = '%11.10g';
            app.StimulusFreqHzEditField.Value = 20;
            app.PlotcolorDropDownLabel = uilabel(app.IndividualNeuronAnalysisTab);
            app.PlotcolorDropDownLabel.Text = 'Plot color';
            app.PlotcolorDropDown = uidropdown(app.IndividualNeuronAnalysisTab);
            app.PlotcolorDropDown.Items = {'Black_', 'Blue__', 'Red___', 'Green_', 'Orange', 'Purple', 'Pink__'};
            app.PlotcolorDropDown.Value = 'Black_';
            app.GroupComparisonsTab = uitab(app.TabGroup);
            app.GroupComparisonsTab.Title = 'Group Comparisons';
            app.GroupComparisonsTab.Units = 'pixels';
            app.ExCellEditField_2Label = uilabel(app.GroupComparisonsTab);
            app.ExCellEditField_2Label.Text = 'Ex. Cell';
            app.ExCellGrp2EditField = uieditfield(app.GroupComparisonsTab, 'numeric');
            app.ExCellGrp2EditField.Value = 1;
            app.ExpgrponeCheckBox = uicheckbox(app.GroupComparisonsTab);
            app.ExpgrponeCheckBox.Text = 'Exp grp one';
            app.ExpgrponeCheckBox.Value = true;
            app.ExpgrptwoCheckBox = uicheckbox(app.GroupComparisonsTab);
            app.ExpgrptwoCheckBox.Text = 'Exp grp two';
            app.ExpgrptwoCheckBox.Value = true;
            app.PairedAnalysisCheckBox = uicheckbox(app.GroupComparisonsTab);
            app.PairedAnalysisCheckBox.Text = 'Paired Analysis';
            app.PairedAnalysisCheckBox.Value = true;
            app.Grp1EditFieldLabel = uilabel(app.GroupComparisonsTab);
            app.Grp1EditFieldLabel.Text = 'Grp 1:';
            app.Grp1EditField = uieditfield(app.GroupComparisonsTab, 'text');
            app.Grp2EditFieldLabel = uilabel(app.GroupComparisonsTab);
            app.Grp2EditFieldLabel.Text = 'Grp 2:';
            app.Grp2EditField = uieditfield(app.GroupComparisonsTab, 'text');
            app.ExCellEditField_4Label = uilabel(app.GroupComparisonsTab);
            app.ExCellEditField_4Label.Text = 'Ex. Cell';
            app.ExCellGrp1EditField = uieditfield(app.GroupComparisonsTab, 'numeric');
            app.ExCellGrp1EditField.Value = 1;
            app.StimulusEvokedSpikingAnalysisLabel_2 = uilabel(app.GroupComparisonsTab);
            app.StimulusEvokedSpikingAnalysisLabel_2.Text = 'Stimulus Evoked Spiking Analysis';
            app.InitialParamsLabel_2 = uilabel(app.GroupComparisonsTab);
            app.InitialParamsLabel_2.Text = 'Initial Params';
            app.GrouponecolorDropDownLabel = uilabel(app.GroupComparisonsTab);
            app.GrouponecolorDropDownLabel.Text = 'Group one color';
            app.GrouponecolorDropDown = uidropdown(app.GroupComparisonsTab);
            app.GrouponecolorDropDown.Items = {'Black_', 'Blue__', 'Red___', 'Green_', 'Orange', 'Purple', 'Pink__'};
            app.GrouponecolorDropDown.Value = 'Black_';
            app.GrouptwocolorDropDownLabel = uilabel(app.GroupComparisonsTab);
            app.GrouptwocolorDropDownLabel.Text = 'Group two color';
            app.GrouptwocolorDropDown = uidropdown(app.GroupComparisonsTab);
            app.GrouptwocolorDropDown.Items = {'Black_', 'Blue__', 'Red___', 'Green_', 'Orange', 'Purple', 'Pink__'};
            app.GrouptwocolorDropDown.Value = 'Black_';
            app.PickFolderButton = uibutton(app.UIFigure, 'push');
            app.PickFolderButton.ButtonPushedFcn = createCallbackFcn(app, @PickFolderButtonPushed, true);
            app.PickFolderButton.Text = 'Pick Folder';
            app.RunButton = uibutton(app.UIFigure, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'Run';
            app.FolderTBDLabel = uilabel(app.UIFigure);
            app.FolderTBDLabel.Text = 'Folder TBD';
            app.SaveButton = uibutton(app.UIFigure, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.SamplerateHzEditFieldLabel = uilabel(app.UIFigure);
            app.SamplerateHzEditFieldLabel.Text = 'Sample rate (Hz)';
            app.SamplerateHzEditField = uieditfield(app.UIFigure, 'numeric');
            app.SamplerateHzEditField.Limits = [0 Inf];
            app.SamplerateHzEditField.ValueDisplayFormat = '%11.10g';
            app.SamplerateHzEditField.Value = 10000;
        end
    end

    methods (Access = public)

        % Construct app
        function app = stimSpkSuite()

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end