classdef ffiSuite < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        IndNeuronAnalysisTab            matlab.ui.container.Tab
        InitialParamsLabel              matlab.ui.control.Label
        FeedforwardInhibitionAnalysisLabel  matlab.ui.control.Label
        RainjectionstartmsEditFieldLabel  matlab.ui.control.Label
        RainjectionstartmsEditField     matlab.ui.control.NumericEditField
        RainjectionendmsEditFieldLabel  matlab.ui.control.Label
        RainjectionendmsEditField       matlab.ui.control.NumericEditField
        GroupComparisonsTab             matlab.ui.container.Tab
        FeedforwardInhibitionAnalysisLabel_2  matlab.ui.control.Label
        ExpgrponeCheckBox               matlab.ui.control.CheckBox
        ExpgrptwoCheckBox               matlab.ui.control.CheckBox
        ExpgrpthreeCheckBox             matlab.ui.control.CheckBox
        Grp1EditFieldLabel              matlab.ui.control.Label
        Grp1EditField                   matlab.ui.control.EditField
        Grp2EditFieldLabel              matlab.ui.control.Label
        Grp2EditField                   matlab.ui.control.EditField
        Grp3EditFieldLabel              matlab.ui.control.Label
        Grp3EditField                   matlab.ui.control.EditField
        ExCellEditFieldLabel            matlab.ui.control.Label
        ExCellEditField                 matlab.ui.control.NumericEditField
        PairedAnalysisTwogroupsonlyCheckBox  matlab.ui.control.CheckBox
        LatencyTestDropDownLabel        matlab.ui.control.Label
        LatencyTestDropDown             matlab.ui.control.DropDown
        KineticsAnalysisCheckBox        matlab.ui.control.CheckBox
        PickFolderButton                matlab.ui.control.Button
        RunButton                       matlab.ui.control.Button
        FolderTBDLabel                  matlab.ui.control.Label
        SaveButton                      matlab.ui.control.Button
        SamplerateHzEditFieldLabel      matlab.ui.control.Label
        SamplerateHzEditField           matlab.ui.control.NumericEditField
        StimulusstartmsEditFieldLabel   matlab.ui.control.Label
        StimulusstartmsEditField        matlab.ui.control.NumericEditField
        PlotcolorDropDownLabel          matlab.ui.control.Label
        PlotcolorDropDown               matlab.ui.control.DropDown
        BaselinedurationmsEditFieldLabel  matlab.ui.control.Label
        BaselinedurationmsEditField     matlab.ui.control.NumericEditField
    end

    
    properties (Access = public)
        ffiDir % directory for data
        ffiData %output data from FFI function
        ffiCompData %structure for FFI comparisons
        plotColor %color choice for plotting
    end
    

    methods (Access = private)

        % Button pushed function: PickFolderButton
        function PickFolderButtonPushed(app, event)
            clear app.ffiDir
            
            if strcmp(app.TabGroup.SelectedTab.Title,'Ind Neuron Analysis')
                app.ffiDir=uigetdir;
                cd(app.ffiDir);
                
                thisPt = find(app.ffiDir=='/');
                app.FolderTBDLabel.Text = app.ffiDir(thisPt(end-1)+1:end);
            elseif strcmp(app.TabGroup.SelectedTab.Title,'Group Comparisons')
                app.ffiDir=uigetdir;
                thisPt = find(app.ffiDir=='/');
                app.FolderTBDLabel.Text = app.ffiDir(thisPt(end)+1:end);
            end
        end

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
            clear app.ffiData
            
            if strcmp(app.TabGroup.SelectedTab.Title,'Ind Neuron Analysis')
                
                app.plotColor = zeros(1,3);
                if app.PlotcolorDropDown.Value == 'Black_'
                    app.plotColor = [0 0 0];
                elseif app.PlotcolorDropDown.Value == 'Blue__'
                    app.plotColor = [.0625 .2545 .433];
                elseif app.PlotcolorDropDown.Value == 'Red___'
                    app.plotColor = [.3922 0 0];
                elseif app.PlotcolorDropDown.Value == 'Green_'
                    app.plotColor = [.35 .6 .25];
                elseif app.PlotcolorDropDown.Value == 'Orange'
                    app.plotColor = [.85 .35 .1];
                elseif app.PlotcolorDropDown.Value == 'Purple'
                    app.plotColor = [.5 .33 .55];
                elseif app.PlotcolorDropDown.Value == 'Pink__'
                    app.plotColor = [.5 .11 .2];
                end
                
                close all
                
                app.ffiData = ffiFxn(app);
            elseif strcmp(app.TabGroup.SelectedTab.Title,'Group Comparisons')
                app.plotColor = zeros(1,3);
                if app.PlotcolorDropDown.Value == 'Black_'
                    app.plotColor = [0 0 0];
                elseif app.PlotcolorDropDown.Value == 'Blue__'
                    app.plotColor = [.0625 .2545 .433];
                elseif app.PlotcolorDropDown.Value == 'Red___'
                    app.plotColor = [.3922 0 0];
                elseif app.PlotcolorDropDown.Value == 'Green_'
                    app.plotColor = [.35 .6 .25];
                elseif app.PlotcolorDropDown.Value == 'Orange'
                    app.plotColor = [.85 .35 .1];
                elseif app.PlotcolorDropDown.Value == 'Purple'
                    app.plotColor = [.5 .33 .55];
                elseif app.PlotcolorDropDown.Value == 'Pink__'
                    app.plotColor = [.5 .11 .2];
                end
                
                close all
                
                app.ffiCompData = ffiCompFxn(app);
            end
            
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            clear save_file path_save
            [save_file,path_save]=uiputfile;
            full_save_file=fullfile(path_save,save_file);
            save(full_save_file);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            setAutoResize(app, app.UIFigure, true)
            app.TabGroup = uitabgroup(app.UIFigure);
            app.IndNeuronAnalysisTab = uitab(app.TabGroup);
            app.IndNeuronAnalysisTab.Title = 'Ind Neuron Analysis';
            app.IndNeuronAnalysisTab.Units = 'pixels';
            app.InitialParamsLabel = uilabel(app.IndNeuronAnalysisTab);
            app.InitialParamsLabel.Text = 'Initial Params';
            app.FeedforwardInhibitionAnalysisLabel = uilabel(app.IndNeuronAnalysisTab);
            app.FeedforwardInhibitionAnalysisLabel.Text = 'Feedforward Inhibition Analysis';
            app.RainjectionstartmsEditFieldLabel = uilabel(app.IndNeuronAnalysisTab);
            app.RainjectionstartmsEditFieldLabel.Text = 'Ra injection start (ms)';
            app.RainjectionstartmsEditField = uieditfield(app.IndNeuronAnalysisTab, 'numeric');
            app.RainjectionstartmsEditField.Limits = [0 Inf];
            app.RainjectionstartmsEditField.ValueDisplayFormat = '%11.10g';
            app.RainjectionstartmsEditField.Value = 562.5;
            app.RainjectionendmsEditFieldLabel = uilabel(app.IndNeuronAnalysisTab);
            app.RainjectionendmsEditFieldLabel.Text = 'Ra injection end (ms)';
            app.RainjectionendmsEditField = uieditfield(app.IndNeuronAnalysisTab, 'numeric');
            app.RainjectionendmsEditField.Limits = [0 Inf];
            app.RainjectionendmsEditField.ValueDisplayFormat = '%11.10g';
            app.RainjectionendmsEditField.Value = 662.4;
            app.GroupComparisonsTab = uitab(app.TabGroup);
            app.GroupComparisonsTab.Title = 'Group Comparisons';
            app.GroupComparisonsTab.Units = 'pixels';
            app.FeedforwardInhibitionAnalysisLabel_2 = uilabel(app.GroupComparisonsTab);
            app.FeedforwardInhibitionAnalysisLabel_2.Text = 'Feedforward Inhibition Analysis';
            app.ExpgrponeCheckBox = uicheckbox(app.GroupComparisonsTab);
            app.ExpgrponeCheckBox.Text = 'Exp grp one';
            app.ExpgrponeCheckBox.Value = true;
            app.ExpgrptwoCheckBox = uicheckbox(app.GroupComparisonsTab);
            app.ExpgrptwoCheckBox.Text = 'Exp grp two';
            app.ExpgrptwoCheckBox.Value = true;
            app.ExpgrpthreeCheckBox = uicheckbox(app.GroupComparisonsTab);
            app.ExpgrpthreeCheckBox.Text = 'Exp grp three';
            app.Grp1EditFieldLabel = uilabel(app.GroupComparisonsTab);
            app.Grp1EditFieldLabel.Text = 'Grp 1:';
            app.Grp1EditField = uieditfield(app.GroupComparisonsTab, 'text');
            app.Grp2EditFieldLabel = uilabel(app.GroupComparisonsTab);
            app.Grp2EditFieldLabel.Text = 'Grp 2:';
            app.Grp2EditField = uieditfield(app.GroupComparisonsTab, 'text');
            app.Grp3EditFieldLabel = uilabel(app.GroupComparisonsTab);
            app.Grp3EditFieldLabel.Text = 'Grp 3:';
            app.Grp3EditField = uieditfield(app.GroupComparisonsTab, 'text');
            app.ExCellEditFieldLabel = uilabel(app.GroupComparisonsTab);
            app.ExCellEditFieldLabel.Text = 'Ex. Cell';
            app.ExCellEditField = uieditfield(app.GroupComparisonsTab, 'numeric');
            app.ExCellEditField.Value = 1;
            app.PairedAnalysisTwogroupsonlyCheckBox = uicheckbox(app.GroupComparisonsTab);
            app.PairedAnalysisTwogroupsonlyCheckBox.Text = 'Paired Analysis (Two groups only)';
            app.PairedAnalysisTwogroupsonlyCheckBox.Value = true;
            app.LatencyTestDropDownLabel = uilabel(app.GroupComparisonsTab);
            app.LatencyTestDropDownLabel.Text = 'Latency Test';
            app.LatencyTestDropDown = uidropdown(app.GroupComparisonsTab);
            app.LatencyTestDropDown.Items = {'Ctrl only', 'All groups'};
            app.LatencyTestDropDown.Value = 'Ctrl only';
            app.KineticsAnalysisCheckBox = uicheckbox(app.GroupComparisonsTab);
            app.KineticsAnalysisCheckBox.Text = 'Kinetics Analysis';
            app.PickFolderButton = uibutton(app.UIFigure, 'push');
            app.PickFolderButton.ButtonPushedFcn = createCallbackFcn(app, @PickFolderButtonPushed, true);
            app.PickFolderButton.Text = 'Pick Folder';
            app.RunButton = uibutton(app.UIFigure, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'Run';
            app.FolderTBDLabel = uilabel(app.UIFigure);
            app.FolderTBDLabel.Text = 'Folder TBD';
            app.SaveButton = uibutton(app.UIFigure, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.SamplerateHzEditFieldLabel = uilabel(app.UIFigure);
            app.SamplerateHzEditFieldLabel.Text = 'Sample rate (Hz)';
            app.SamplerateHzEditField = uieditfield(app.UIFigure, 'numeric');
            app.SamplerateHzEditField.Limits = [0 Inf];
            app.SamplerateHzEditField.ValueDisplayFormat = '%11.10g';
            app.SamplerateHzEditField.Value = 10000;
            app.StimulusstartmsEditFieldLabel = uilabel(app.UIFigure);
            app.StimulusstartmsEditFieldLabel.Text = 'Stimulus start (ms)';
            app.StimulusstartmsEditField = uieditfield(app.UIFigure, 'numeric');
            app.StimulusstartmsEditField.Limits = [0 Inf];
            app.StimulusstartmsEditField.ValueDisplayFormat = '%11.10g';
            app.StimulusstartmsEditField.Value = 2562.5;
            app.PlotcolorDropDownLabel = uilabel(app.UIFigure);
            app.PlotcolorDropDownLabel.Text = 'Plot color';
            app.PlotcolorDropDown = uidropdown(app.UIFigure);
            app.PlotcolorDropDown.Items = {'Black_', 'Blue__', 'Red___', 'Green_', 'Orange', 'Purple', 'Pink__'};
            app.PlotcolorDropDown.Value = 'Black_';
            app.BaselinedurationmsEditFieldLabel = uilabel(app.UIFigure);
            app.BaselinedurationmsEditFieldLabel.Text = 'Baseline duration (ms)';
            app.BaselinedurationmsEditField = uieditfield(app.UIFigure, 'numeric');
            app.BaselinedurationmsEditField.Limits = [0 Inf];
            app.BaselinedurationmsEditField.ValueDisplayFormat = '%11.10g';
            app.BaselinedurationmsEditField.Value = 500;
        end
    end

    methods (Access = public)

        % Construct app
        function app = ffiSuite()

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end