classdef minStimSuite < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        IndNeuronAnalysisTab            matlab.ui.container.Tab
        InitialParamsLabel              matlab.ui.control.Label
        MinimalStimulationAnalysisLabel  matlab.ui.control.Label
        RainjectionstartmsEditFieldLabel  matlab.ui.control.Label
        RainjectionstartmsEditField     matlab.ui.control.NumericEditField
        RainjectionendmsEditFieldLabel  matlab.ui.control.Label
        RainjectionendmsEditField       matlab.ui.control.NumericEditField
        PlotcolorDropDownLabel          matlab.ui.control.Label
        PlotcolorDropDown               matlab.ui.control.DropDown
        GroupComparisonsTab             matlab.ui.container.Tab
        MinimalStimulationAnalysisLabel_2  matlab.ui.control.Label
        ExpgrponeCheckBox               matlab.ui.control.CheckBox
        ExpgrptwoCheckBox               matlab.ui.control.CheckBox
        ExpgrpthreeCheckBox             matlab.ui.control.CheckBox
        Grp1EditFieldLabel              matlab.ui.control.Label
        Grp1EditField                   matlab.ui.control.EditField
        Grp2EditFieldLabel              matlab.ui.control.Label
        Grp2EditField                   matlab.ui.control.EditField
        Grp3EditFieldLabel              matlab.ui.control.Label
        Grp3EditField                   matlab.ui.control.EditField
        ExCellEditFieldLabel            matlab.ui.control.Label
        ExCellEditField                 matlab.ui.control.NumericEditField
        ExCellEditField_2Label          matlab.ui.control.Label
        ExCellEditField_2               matlab.ui.control.NumericEditField
        ExCellEditField_3Label          matlab.ui.control.Label
        ExCellEditField_3               matlab.ui.control.NumericEditField
        GrponeplotcolorDropDownLabel    matlab.ui.control.Label
        GrponeplotcolorDropDown         matlab.ui.control.DropDown
        GrptwoplotcolorDropDownLabel    matlab.ui.control.Label
        GrptwoplotcolorDropDown         matlab.ui.control.DropDown
        GrpthreeplotcolorDropDownLabel  matlab.ui.control.Label
        GrpthreeplotcolorDropDown       matlab.ui.control.DropDown
        WithinGroupBiophysComparisonsTab  matlab.ui.container.Tab
        MinimalStimulationAnalysisLabel_3  matlab.ui.control.Label
        GroupEditFieldLabel             matlab.ui.control.Label
        GroupEditField                  matlab.ui.control.EditField
        ExCellrespondersEditFieldLabel  matlab.ui.control.Label
        ExCellrespondersEditField       matlab.ui.control.NumericEditField
        ExCellnonrespondersEditFieldLabel  matlab.ui.control.Label
        ExCellnonrespondersEditField    matlab.ui.control.NumericEditField
        RmCheckBox                      matlab.ui.control.CheckBox
        maxFRCheckBox                   matlab.ui.control.CheckBox
        APlatencyCheckBox               matlab.ui.control.CheckBox
        halfwidthCheckBox               matlab.ui.control.CheckBox
        sagCheckBox                     matlab.ui.control.CheckBox
        APthreshCheckBox                matlab.ui.control.CheckBox
        AHPmagCheckBox                  matlab.ui.control.CheckBox
        AHPlatCheckBox                  matlab.ui.control.CheckBox
        deltaAHPCheckBox                matlab.ui.control.CheckBox
        AmpadaptCheckBox                matlab.ui.control.CheckBox
        FRadaptCheckBox                 matlab.ui.control.CheckBox
        mTauCheckBox                    matlab.ui.control.CheckBox
        APampCheckBox                   matlab.ui.control.CheckBox
        APbroadeningCheckBox            matlab.ui.control.CheckBox
        reboundAPsCheckBox              matlab.ui.control.CheckBox
        PlotcolorDropDown_2Label        matlab.ui.control.Label
        PlotcolorDropDown_2             matlab.ui.control.DropDown
        PickFolderButton                matlab.ui.control.Button
        RunButton                       matlab.ui.control.Button
        FolderTBDLabel                  matlab.ui.control.Label
        SaveButton                      matlab.ui.control.Button
        SamplerateHzEditFieldLabel      matlab.ui.control.Label
        SamplerateHzEditField           matlab.ui.control.NumericEditField
        StimulusstartmsEditFieldLabel   matlab.ui.control.Label
        StimulusstartmsEditField        matlab.ui.control.NumericEditField
        BaselinedurationmsEditFieldLabel  matlab.ui.control.Label
        BaselinedurationmsEditField     matlab.ui.control.NumericEditField
    end

    
    properties (Access = public)
        minStimDir % directory for files
        minStimData %data structure
        minStimCompData %data structure for comparisons
        minStimWnGrpCompData %data structure for within group biophysics analysis
        plotColor %plot color choice for single cell
        plotColorGrps %plot color choices for grp comparions
    end
    

    methods (Access = private)

        % Button pushed function: PickFolderButton
        function PickFolderButtonPushed(app, event)
            clear app.minStimDir
            
            if strcmp(app.TabGroup.SelectedTab.Title,'Ind Neuron Analysis')
                app.minStimDir=uigetdir;
                cd(app.minStimDir);
                
                thisPt = find(app.minStimDir=='/');
                app.FolderTBDLabel.Text = app.minStimDir(thisPt(end-1)+1:end);
                
            elseif strcmp(app.TabGroup.SelectedTab.Title,'Group Comparisons')
                app.minStimDir=uigetdir;
                thisPt = find(app.minStimDir=='/');
                app.FolderTBDLabel.Text = app.minStimDir(thisPt(end)+1:end);
            elseif strcmp(app.TabGroup.SelectedTab.Title,'Within Group Biophys Comparisons')
                app.minStimDir=uigetdir;
                thisPt = find(app.minStimDir=='/');
                app.FolderTBDLabel.Text = app.minStimDir(thisPt(end)+1:end);
            end
        end

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
            clear app.minStimData
            
            if strcmp(app.TabGroup.SelectedTab.Title,'Ind Neuron Analysis')
                
                app.plotColor = zeros(1,3);
                if app.PlotcolorDropDown.Value == 'Black_'
                    app.plotColor = [0 0 0];
                elseif app.PlotcolorDropDown.Value == 'Blue__'
                    app.plotColor = [.0625 .2545 .433];
                elseif app.PlotcolorDropDown.Value == 'Red___'
                    app.plotColor = [.3922 0 0];
                elseif app.PlotcolorDropDown.Value == 'Green_'
                    app.plotColor = [.35 .6 .25];
                elseif app.PlotcolorDropDown.Value == 'Orange'
                    app.plotColor = [.85 .35 .1];
                elseif app.PlotcolorDropDown.Value == 'Purple'
                    app.plotColor = [.5 .33 .55];
                elseif app.PlotcolorDropDown.Value == 'Pink__'
                    app.plotColor = [.5 .11 .2];
                end
                
                close all
                
                app.minStimData = minstimFxn(app);
            elseif strcmp(app.TabGroup.SelectedTab.Title,'Group Comparisons')
                clear app.minStimCompData
                clear app.minStimData
                close all
                
                totalGroups = sum([app.ExpgrponeCheckBox.Value app.ExpgrptwoCheckBox.Value app.ExpgrpthreeCheckBox.Value]);
                %init colors
                app.plotColorGrps = zeros(totalGroups,3);
                for ii = 1:totalGroups
                    if ii == 1
                        if app.GrponeplotcolorDropDown.Value == 'Black_'
                            app.plotColorGrps(ii,:) = [0 0 0];
                        elseif app.GrponeplotcolorDropDown.Value == 'Blue__'
                            app.plotColorGrps(ii,:) = [.0625 .2545 .433];
                        elseif app.GrponeplotcolorDropDown.Value == 'Red___'
                            app.plotColorGrps(ii,:) = [.3922 0 0];
                        elseif app.GrponeplotcolorDropDown.Value == 'Green_'
                            app.plotColorGrps(ii,:) = [.35 .6 .25];
                        elseif app.GrponeplotcolorDropDown.Value == 'Orange'
                            app.plotColorGrps(ii,:) = [.85 .35 .1];
                        elseif app.GrponeplotcolorDropDown.Value == 'Purple'
                            app.plotColorGrps(ii,:) = [.5 .33 .55];
                        elseif app.GrponeplotcolorDropDown.Value == 'Pink__'
                            app.plotColorGrps(ii,:) = [.5 .11 .2];
                        end
                    elseif ii == 2
                        if app.GrptwoplotcolorDropDown.Value == 'Black_'
                            app.plotColorGrps(ii,:) = [0 0 0];
                        elseif app.GrptwoplotcolorDropDown.Value == 'Blue__'
                            app.plotColorGrps(ii,:) = [.0625 .2545 .433];
                        elseif app.GrptwoplotcolorDropDown.Value == 'Red___'
                            app.plotColorGrps(ii,:) = [.3922 0 0];
                        elseif app.GrptwoplotcolorDropDown.Value == 'Green_'
                            app.plotColorGrps(ii,:) = [.35 .6 .25];
                        elseif app.GrptwoplotcolorDropDown.Value == 'Orange'
                            app.plotColorGrps(ii,:) = [.85 .35 .1];
                        elseif app.GrptwoplotcolorDropDown.Value == 'Purple'
                            app.plotColorGrps(ii,:) = [.5 .33 .55];
                        elseif app.GrptwoplotcolorDropDown.Value == 'Pink__'
                            app.plotColorGrps(ii,:) = [.5 .11 .2];
                        end
                    elseif ii == 3
                        if app.GrpthreeplotcolorDropDown.Value == 'Black_'
                            app.plotColorGrps(ii,:) = [0 0 0];
                        elseif app.GrpthreeplotcolorDropDown.Value == 'Blue__'
                            app.plotColorGrps(ii,:) = [.0625 .2545 .433];
                        elseif app.GrpthreeplotcolorDropDown.Value == 'Red___'
                            app.plotColorGrps(ii,:) = [.3922 0 0];
                        elseif app.GrpthreeplotcolorDropDown.Value == 'Green_'
                            app.plotColorGrps(ii,:) = [.35 .6 .25];
                        elseif app.GrpthreeplotcolorDropDown.Value == 'Orange'
                            app.plotColorGrps(ii,:) = [.85 .35 .1];
                        elseif app.GrpthreeplotcolorDropDown.Value == 'Purple'
                            app.plotColorGrps(ii,:) = [.5 .33 .55];
                        elseif app.GrpthreeplotcolorDropDown.Value == 'Pink__'
                            app.plotColorGrps(ii,:) = [.5 .11 .2];
                        end
                    end
                end
                
                app.minStimCompData = minStimCompFxn(app);
            elseif strcmp(app.TabGroup.SelectedTab.Title,'Within Group Biophys Comparisons')
                app.plotColor = zeros(1,3);
                if app.PlotcolorDropDown_2.Value == 'Black_'
                    app.plotColor = [0 0 0];
                elseif app.PlotcolorDropDown_2.Value == 'Blue__'
                    app.plotColor = [.0625 .2545 .433];
                elseif app.PlotcolorDropDown_2.Value == 'Red___'
                    app.plotColor = [.3922 0 0];
                elseif app.PlotcolorDropDown_2.Value == 'Green_'
                    app.plotColor = [.35 .6 .25];
                elseif app.PlotcolorDropDown_2.Value == 'Orange'
                    app.plotColor = [.85 .35 .1];
                elseif app.PlotcolorDropDown_2.Value == 'Purple'
                    app.plotColor = [.5 .33 .55];
                elseif app.PlotcolorDropDown_2.Value == 'Pink__'
                    app.plotColor = [.5 .11 .2];
                end
                
                close all
                
                app.minStimWnGrpCompData = wnGrpBiophysCompFxn(app);
            end
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            clear save_file path_save
            [save_file,path_save]=uiputfile;
            full_save_file=fullfile(path_save,save_file);
            save(full_save_file);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.IndNeuronAnalysisTab = uitab(app.TabGroup);
            app.IndNeuronAnalysisTab.Title = 'Ind Neuron Analysis';
            app.InitialParamsLabel = uilabel(app.IndNeuronAnalysisTab);
            app.InitialParamsLabel.Text = 'Initial Params';
            app.MinimalStimulationAnalysisLabel = uilabel(app.IndNeuronAnalysisTab);
            app.MinimalStimulationAnalysisLabel.Text = 'Minimal Stimulation Analysis';
            app.RainjectionstartmsEditFieldLabel = uilabel(app.IndNeuronAnalysisTab);
            app.RainjectionstartmsEditFieldLabel.Text = 'Ra injection start (ms)';
            app.RainjectionstartmsEditField = uieditfield(app.IndNeuronAnalysisTab, 'numeric');
            app.RainjectionstartmsEditField.Limits = [0 Inf];
            app.RainjectionstartmsEditField.ValueDisplayFormat = '%11.10g';
            app.RainjectionstartmsEditField.Value = 562.5;
            app.RainjectionendmsEditFieldLabel = uilabel(app.IndNeuronAnalysisTab);
            app.RainjectionendmsEditFieldLabel.Text = 'Ra injection end (ms)';
            app.RainjectionendmsEditField = uieditfield(app.IndNeuronAnalysisTab, 'numeric');
            app.RainjectionendmsEditField.Limits = [0 Inf];
            app.RainjectionendmsEditField.ValueDisplayFormat = '%11.10g';
            app.RainjectionendmsEditField.Value = 662.4;
            app.PlotcolorDropDownLabel = uilabel(app.IndNeuronAnalysisTab);
            app.PlotcolorDropDownLabel.Text = 'Plot color';
            app.PlotcolorDropDown = uidropdown(app.IndNeuronAnalysisTab);
            app.PlotcolorDropDown.Items = {'Black_', 'Blue__', 'Red___', 'Green_', 'Orange', 'Purple', 'Pink__'};
            app.PlotcolorDropDown.Value = 'Black_';
            app.GroupComparisonsTab = uitab(app.TabGroup);
            app.GroupComparisonsTab.Title = 'Group Comparisons';
            app.MinimalStimulationAnalysisLabel_2 = uilabel(app.GroupComparisonsTab);
            app.MinimalStimulationAnalysisLabel_2.Text = 'Minimal Stimulation Analysis';
            app.ExpgrponeCheckBox = uicheckbox(app.GroupComparisonsTab);
            app.ExpgrponeCheckBox.Text = 'Exp grp one';
            app.ExpgrponeCheckBox.Value = true;
            app.ExpgrptwoCheckBox = uicheckbox(app.GroupComparisonsTab);
            app.ExpgrptwoCheckBox.Text = 'Exp grp two';
            app.ExpgrptwoCheckBox.Value = true;
            app.ExpgrpthreeCheckBox = uicheckbox(app.GroupComparisonsTab);
            app.ExpgrpthreeCheckBox.Text = 'Exp grp three';
            app.Grp1EditFieldLabel = uilabel(app.GroupComparisonsTab);
            app.Grp1EditFieldLabel.Text = 'Grp 1:';
            app.Grp1EditField = uieditfield(app.GroupComparisonsTab, 'text');
            app.Grp1EditField.Value = 'PrincipalNeurons';
            app.Grp2EditFieldLabel = uilabel(app.GroupComparisonsTab);
            app.Grp2EditFieldLabel.Text = 'Grp 2:';
            app.Grp2EditField = uieditfield(app.GroupComparisonsTab, 'text');
            app.Grp3EditFieldLabel = uilabel(app.GroupComparisonsTab);
            app.Grp3EditFieldLabel.Text = 'Grp 3:';
            app.Grp3EditField = uieditfield(app.GroupComparisonsTab, 'text');
            app.ExCellEditFieldLabel = uilabel(app.GroupComparisonsTab);
            app.ExCellEditFieldLabel.Text = 'Ex. Cell';
            app.ExCellEditField = uieditfield(app.GroupComparisonsTab, 'numeric');
            app.ExCellEditField.Value = 7;
            app.ExCellEditField_2Label = uilabel(app.GroupComparisonsTab);
            app.ExCellEditField_2Label.Text = 'Ex. Cell';
            app.ExCellEditField_2 = uieditfield(app.GroupComparisonsTab, 'numeric');
            app.ExCellEditField_2.Value = 2;
            app.ExCellEditField_3Label = uilabel(app.GroupComparisonsTab);
            app.ExCellEditField_3Label.Text = 'Ex. Cell';
            app.ExCellEditField_3 = uieditfield(app.GroupComparisonsTab, 'numeric');
            app.ExCellEditField_3.Value = 11;
            app.GrponeplotcolorDropDownLabel = uilabel(app.GroupComparisonsTab);
            app.GrponeplotcolorDropDownLabel.Text = 'Grp one plot color';
            app.GrponeplotcolorDropDown = uidropdown(app.GroupComparisonsTab);
            app.GrponeplotcolorDropDown.Items = {'Black_', 'Blue__', 'Red___', 'Green_', 'Orange', 'Purple', 'Pink__'};
            app.GrponeplotcolorDropDown.Value = 'Black_';
            app.GrptwoplotcolorDropDownLabel = uilabel(app.GroupComparisonsTab);
            app.GrptwoplotcolorDropDownLabel.Text = 'Grp two plot color';
            app.GrptwoplotcolorDropDown = uidropdown(app.GroupComparisonsTab);
            app.GrptwoplotcolorDropDown.Items = {'Black_', 'Blue__', 'Red___', 'Green_', 'Orange', 'Purple', 'Pink__'};
            app.GrptwoplotcolorDropDown.Value = 'Black_';
            app.GrpthreeplotcolorDropDownLabel = uilabel(app.GroupComparisonsTab);
            app.GrpthreeplotcolorDropDownLabel.Text = 'Grp three plot color';
            app.GrpthreeplotcolorDropDown = uidropdown(app.GroupComparisonsTab);
            app.GrpthreeplotcolorDropDown.Items = {'Black_', 'Blue__', 'Red___', 'Green_', 'Orange', 'Purple', 'Pink__'};
            app.GrpthreeplotcolorDropDown.Value = 'Black_';
            app.WithinGroupBiophysComparisonsTab = uitab(app.TabGroup);
            app.WithinGroupBiophysComparisonsTab.Title = 'Within Group Biophys Comparisons';
            app.MinimalStimulationAnalysisLabel_3 = uilabel(app.WithinGroupBiophysComparisonsTab);
            app.MinimalStimulationAnalysisLabel_3.Text = 'Minimal Stimulation Analysis';
            app.GroupEditFieldLabel = uilabel(app.WithinGroupBiophysComparisonsTab);
            app.GroupEditFieldLabel.Text = 'Group';
            app.GroupEditField = uieditfield(app.WithinGroupBiophysComparisonsTab, 'text');
            app.GroupEditField.Value = 'Sst-tdTomato';
            app.ExCellrespondersEditFieldLabel = uilabel(app.WithinGroupBiophysComparisonsTab);
            app.ExCellrespondersEditFieldLabel.Text = 'Ex. Cell, responders';
            app.ExCellrespondersEditField = uieditfield(app.WithinGroupBiophysComparisonsTab, 'numeric');
            app.ExCellrespondersEditField.Value = 1;
            app.ExCellnonrespondersEditFieldLabel = uilabel(app.WithinGroupBiophysComparisonsTab);
            app.ExCellnonrespondersEditFieldLabel.Text = 'Ex. Cell, nonresponders';
            app.ExCellnonrespondersEditField = uieditfield(app.WithinGroupBiophysComparisonsTab, 'numeric');
            app.ExCellnonrespondersEditField.Value = 1;
            app.RmCheckBox = uicheckbox(app.WithinGroupBiophysComparisonsTab);
            app.RmCheckBox.Text = 'Rm';
            app.maxFRCheckBox = uicheckbox(app.WithinGroupBiophysComparisonsTab);
            app.maxFRCheckBox.Text = 'max FR';
            app.APlatencyCheckBox = uicheckbox(app.WithinGroupBiophysComparisonsTab);
            app.APlatencyCheckBox.Text = 'AP latency';
            app.halfwidthCheckBox = uicheckbox(app.WithinGroupBiophysComparisonsTab);
            app.halfwidthCheckBox.Text = 'halfwidth';
            app.sagCheckBox = uicheckbox(app.WithinGroupBiophysComparisonsTab);
            app.sagCheckBox.Text = 'sag';
            app.APthreshCheckBox = uicheckbox(app.WithinGroupBiophysComparisonsTab);
            app.APthreshCheckBox.Text = 'AP thresh';
            app.AHPmagCheckBox = uicheckbox(app.WithinGroupBiophysComparisonsTab);
            app.AHPmagCheckBox.Text = 'AHP mag';
            app.AHPlatCheckBox = uicheckbox(app.WithinGroupBiophysComparisonsTab);
            app.AHPlatCheckBox.Text = 'AHP lat';
            app.deltaAHPCheckBox = uicheckbox(app.WithinGroupBiophysComparisonsTab);
            app.deltaAHPCheckBox.Text = 'delta AHP';
            app.AmpadaptCheckBox = uicheckbox(app.WithinGroupBiophysComparisonsTab);
            app.AmpadaptCheckBox.Text = 'Amp adapt';
            app.FRadaptCheckBox = uicheckbox(app.WithinGroupBiophysComparisonsTab);
            app.FRadaptCheckBox.Text = 'FR adapt';
            app.mTauCheckBox = uicheckbox(app.WithinGroupBiophysComparisonsTab);
            app.mTauCheckBox.Text = 'mTau';
            app.APampCheckBox = uicheckbox(app.WithinGroupBiophysComparisonsTab);
            app.APampCheckBox.Text = 'AP amp';
            app.APbroadeningCheckBox = uicheckbox(app.WithinGroupBiophysComparisonsTab);
            app.APbroadeningCheckBox.Text = 'AP broadening';
            app.reboundAPsCheckBox = uicheckbox(app.WithinGroupBiophysComparisonsTab);
            app.reboundAPsCheckBox.Text = 'rebound APs';
            app.PlotcolorDropDown_2Label = uilabel(app.WithinGroupBiophysComparisonsTab);
            app.PlotcolorDropDown_2Label.Text = 'Plot color';
            app.PlotcolorDropDown_2 = uidropdown(app.WithinGroupBiophysComparisonsTab);
            app.PlotcolorDropDown_2.Items = {'Black_', 'Blue__', 'Red___', 'Green_', 'Orange', 'Purple', 'Pink__'};
            app.PlotcolorDropDown_2.Value = 'Black_';
            app.PickFolderButton = uibutton(app.UIFigure, 'push');
            app.PickFolderButton.ButtonPushedFcn = createCallbackFcn(app, @PickFolderButtonPushed, true);
            app.PickFolderButton.Text = 'Pick Folder';
            app.RunButton = uibutton(app.UIFigure, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'Run';
            app.FolderTBDLabel = uilabel(app.UIFigure);
            app.FolderTBDLabel.Text = 'Folder TBD';
            app.SaveButton = uibutton(app.UIFigure, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.SamplerateHzEditFieldLabel = uilabel(app.UIFigure);
            app.SamplerateHzEditFieldLabel.Text = 'Sample rate (Hz)';
            app.SamplerateHzEditField = uieditfield(app.UIFigure, 'numeric');
            app.SamplerateHzEditField.Limits = [0 Inf];
            app.SamplerateHzEditField.ValueDisplayFormat = '%11.10g';
            app.SamplerateHzEditField.Value = 10000;
            app.StimulusstartmsEditFieldLabel = uilabel(app.UIFigure);
            app.StimulusstartmsEditFieldLabel.Text = 'Stimulus start (ms)';
            app.StimulusstartmsEditField = uieditfield(app.UIFigure, 'numeric');
            app.StimulusstartmsEditField.Limits = [0 Inf];
            app.StimulusstartmsEditField.ValueDisplayFormat = '%11.10g';
            app.StimulusstartmsEditField.Value = 2562.5;
            app.BaselinedurationmsEditFieldLabel = uilabel(app.UIFigure);
            app.BaselinedurationmsEditFieldLabel.Text = 'Baseline duration (ms)';
            app.BaselinedurationmsEditField = uieditfield(app.UIFigure, 'numeric');
            app.BaselinedurationmsEditField.Limits = [0 Inf];
            app.BaselinedurationmsEditField.ValueDisplayFormat = '%11.10g';
            app.BaselinedurationmsEditField.Value = 500;
        end
    end

    methods (Access = public)

        % Construct app
        function app = minStimSuite

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end