classdef plasticitySuite < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        IndividualNeuronAnalysisTab     matlab.ui.container.Tab
        InitialParamsLabel              matlab.ui.control.Label
        PlasticityAnalysisLabel         matlab.ui.control.Label
        RainjectionstartsecEditFieldLabel  matlab.ui.control.Label
        RainjectionstartsecEditField    matlab.ui.control.NumericEditField
        RainjectionendsecEditFieldLabel  matlab.ui.control.Label
        RainjectionendsecEditField      matlab.ui.control.NumericEditField
        BaselineParamsLabel             matlab.ui.control.Label
        PostindParamsLabel              matlab.ui.control.Label
        baseDurationminEditFieldLabel   matlab.ui.control.Label
        baseDurationminEditField        matlab.ui.control.NumericEditField
        postDurationminEditFieldLabel   matlab.ui.control.Label
        postDurationminEditField        matlab.ui.control.NumericEditField
        PlotcolorDropDownLabel          matlab.ui.control.Label
        PlotcolorDropDown               matlab.ui.control.DropDown
        GroupComparisonsTab             matlab.ui.container.Tab
        PlasticityGroupComparisonsLabel  matlab.ui.control.Label
        ExpgrponeCheckBox               matlab.ui.control.CheckBox
        ExpgrptwoCheckBox               matlab.ui.control.CheckBox
        ExpgrpthreeCheckBox             matlab.ui.control.CheckBox
        Grp1EditFieldLabel              matlab.ui.control.Label
        Grp1EditField                   matlab.ui.control.EditField
        Grp2EditFieldLabel              matlab.ui.control.Label
        Grp2EditField                   matlab.ui.control.EditField
        Grp3EditFieldLabel              matlab.ui.control.Label
        Grp3EditField                   matlab.ui.control.EditField
        GrponeplotcolorDropDownLabel    matlab.ui.control.Label
        GrponeplotcolorDropDown         matlab.ui.control.DropDown
        GrptwoplotcolorDropDownLabel    matlab.ui.control.Label
        GrptwoplotcolorDropDown         matlab.ui.control.DropDown
        GrpthreeplotcolorDropDownLabel  matlab.ui.control.Label
        GrpthreeplotcolorDropDown       matlab.ui.control.DropDown
        SaveButton                      matlab.ui.control.Button
        PickFolderButton                matlab.ui.control.Button
        FolderTBDLabel                  matlab.ui.control.Label
        RunButton                       matlab.ui.control.Button
        StimulusstartsecEditFieldLabel  matlab.ui.control.Label
        StimulusstartsecEditField       matlab.ui.control.NumericEditField
        SamplerateHzEditFieldLabel      matlab.ui.control.Label
        SamplerateHzEditField           matlab.ui.control.NumericEditField
        StimperminEditFieldLabel        matlab.ui.control.Label
        StimperminEditField             matlab.ui.control.NumericEditField
        AnalysisdurationminEditFieldLabel  matlab.ui.control.Label
        AnalysisdurationminEditField    matlab.ui.control.NumericEditField
    end


    properties (Access = public)
        plastDir % directory for data to load
        plastData % data structure for single neurons
        plastCompData %data structure for comparisons
        plotColor % variable holding the color to plot data
        plotColorGrpOne % variable holding the color to plot data
        plotColorGrpTwo % variable holding the color to plot data
        plotColorGrpThree % variable holding the color to plot data
    end


    methods (Access = private)

        % Button pushed function: PickFolderButton
        function PickFolderButtonPushed(app, event)
            clear app.plastDir
            
            if strcmp(app.TabGroup.SelectedTab.Title,'Individual Neuron Analysis')
                app.plastDir=uigetdir;
                cd(app.plastDir);
                thisPt = find(app.plastDir=='/');
                app.FolderTBDLabel.Text = app.plastDir(thisPt(end)+1:end);
            elseif strcmp(app.TabGroup.SelectedTab.Title,'Group Comparisons')
                app.plastDir=uigetdir;
                cd(app.plastDir);
                thisPt = find(app.plastDir=='/');
                app.FolderTBDLabel.Text = app.plastDir(thisPt(end)+1:end);
            end
        end

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
            clear app.plastData
            
            if strcmp(app.TabGroup.SelectedTab.Title,'Individual Neuron Analysis')
                
                app.plotColor = zeros(1,3);
                if app.PlotcolorDropDown.Value == 'Black_'
                    app.plotColor = [0 0 0];
                elseif app.PlotcolorDropDown.Value == 'Blue__'
                    app.plotColor = [.0625 .2545 .433];
                elseif app.PlotcolorDropDown.Value == 'Red___'
                    app.plotColor = [.3922 0 0];
                elseif app.PlotcolorDropDown.Value == 'Green_'
                    app.plotColor = [.35 .6 .25];
                elseif app.PlotcolorDropDown.Value == 'Orange'
                    app.plotColor = [.85 .35 .1];
                elseif app.PlotcolorDropDown.Value == 'Purple'
                    app.plotColor = [.5 .33 .55];
                elseif app.PlotcolorDropDown.Value == 'Pink__'
                    app.plotColor = [.5 .11 .2];
                end
                
                close all
                
                app.plastData = plastFxn(app);
            elseif strcmp(app.TabGroup.SelectedTab.Title,'Group Comparisons')
                clear app.plastCompData
                close all
                
                %group one
                app.plotColorGrpOne = zeros(1,3);
                if app.GrponeplotcolorDropDown.Value == 'Black_'
                    app.plotColorGrpOne = [0 0 0];
                elseif app.GrponeplotcolorDropDown.Value == 'Blue__'
                    app.plotColorGrpOne = [.0625 .2545 .433];
                elseif app.GrponeplotcolorDropDown.Value == 'Red___'
                    app.plotColorGrpOne = [.3922 0 0];
                elseif app.GrponeplotcolorDropDown.Value == 'Green_'
                    app.plotColorGrpOne = [.35 .6 .25];
                elseif app.GrponeplotcolorDropDown.Value == 'Orange'
                    app.plotColorGrpOne = [.85 .35 .1];
                elseif app.GrponeplotcolorDropDown.Value == 'Purple'
                    app.plotColorGrpOne = [.5 .33 .55];
                elseif app.GrponeplotcolorDropDown.Value == 'Pink__'
                    app.plotColorGrpOne = [.5 .11 .2];
                end
                
                %group two
                app.plotColorGrpTwo = zeros(1,3);
                if app.GrptwoplotcolorDropDown.Value == 'Black_'
                    app.plotColorGrpTwo = [0 0 0];
                elseif app.GrptwoplotcolorDropDown.Value == 'Blue__'
                    app.plotColorGrpTwo = [.0625 .2545 .433];
                elseif app.GrptwoplotcolorDropDown.Value == 'Red___'
                    app.plotColorGrpTwo = [.3922 0 0];
                elseif app.GrptwoplotcolorDropDown.Value == 'Green_'
                    app.plotColorGrpTwo = [.35 .6 .25];
                elseif app.GrptwoplotcolorDropDown.Value == 'Orange'
                    app.plotColorGrpTwo = [.85 .35 .1];
                elseif app.GrptwoplotcolorDropDown.Value == 'Purple'
                    app.plotColorGrpTwo = [.5 .33 .55];
                elseif app.GrptwoplotcolorDropDown.Value == 'Pink__'
                    app.plotColorGrpTwo = [.5 .11 .2];
                end
                
                %group three
                app.plotColorGrpThree = zeros(1,3);
                if app.GrpthreeplotcolorDropDown.Value == 'Black_'
                    app.plotColorGrpThree = [0 0 0];
                elseif app.GrpthreeplotcolorDropDown.Value == 'Blue__'
                    app.plotColorGrpThree = [.0625 .2545 .433];
                elseif app.GrpthreeplotcolorDropDown.Value == 'Red___'
                    app.plotColorGrpThree = [.3922 0 0];
                elseif app.GrpthreeplotcolorDropDown.Value == 'Green_'
                    app.plotColorGrpThree = [.35 .6 .25];
                elseif app.GrpthreeplotcolorDropDown.Value == 'Orange'
                    app.plotColorGrpThree = [.85 .35 .1];
                elseif app.GrpthreeplotcolorDropDown.Value == 'Purple'
                    app.plotColorGrpThree = [.5 .33 .55];
                elseif app.GrpthreeplotcolorDropDown.Value == 'Pink__'
                    app.plotColorGrpThree = [.5 .11 .2];
                end
                
                app.plastCompData = plastCompFxn(app);
                
            end
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            clear save_file path_save
            [save_file,path_save]=uiputfile;
            full_save_file=fullfile(path_save,save_file);
            save(full_save_file);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.IndividualNeuronAnalysisTab = uitab(app.TabGroup);
            app.IndividualNeuronAnalysisTab.Title = 'Individual Neuron Analysis';
            app.InitialParamsLabel = uilabel(app.IndividualNeuronAnalysisTab);
            app.InitialParamsLabel.Text = 'Initial Params';
            app.PlasticityAnalysisLabel = uilabel(app.IndividualNeuronAnalysisTab);
            app.PlasticityAnalysisLabel.Text = 'Plasticity Analysis';
            app.RainjectionstartsecEditFieldLabel = uilabel(app.IndividualNeuronAnalysisTab);
            app.RainjectionstartsecEditFieldLabel.Text = 'Ra injection start (sec)';
            app.RainjectionstartsecEditField = uieditfield(app.IndividualNeuronAnalysisTab, 'numeric');
            app.RainjectionstartsecEditField.Limits = [0 Inf];
            app.RainjectionstartsecEditField.ValueDisplayFormat = '%11.10g';
            app.RainjectionstartsecEditField.Value = 0.7343;
            app.RainjectionendsecEditFieldLabel = uilabel(app.IndividualNeuronAnalysisTab);
            app.RainjectionendsecEditFieldLabel.Text = 'Ra injection end (sec)';
            app.RainjectionendsecEditField = uieditfield(app.IndividualNeuronAnalysisTab, 'numeric');
            app.RainjectionendsecEditField.Limits = [0 Inf];
            app.RainjectionendsecEditField.ValueDisplayFormat = '%11.10g';
            app.RainjectionendsecEditField.Value = 0.8342;
            app.BaselineParamsLabel = uilabel(app.IndividualNeuronAnalysisTab);
            app.BaselineParamsLabel.Text = 'Baseline Params';
            app.PostindParamsLabel = uilabel(app.IndividualNeuronAnalysisTab);
            app.PostindParamsLabel.Text = 'Post-ind. Params';
            app.baseDurationminEditFieldLabel = uilabel(app.IndividualNeuronAnalysisTab);
            app.baseDurationminEditFieldLabel.Text = 'Duration (min)';
            app.baseDurationminEditField = uieditfield(app.IndividualNeuronAnalysisTab, 'numeric');
            app.baseDurationminEditField.Value = 5;
            app.postDurationminEditFieldLabel = uilabel(app.IndividualNeuronAnalysisTab);
            app.postDurationminEditFieldLabel.Text = 'Duration (min)';
            app.postDurationminEditField = uieditfield(app.IndividualNeuronAnalysisTab, 'numeric');
            app.postDurationminEditField.Value = 30;
            app.PlotcolorDropDownLabel = uilabel(app.IndividualNeuronAnalysisTab);
            app.PlotcolorDropDownLabel.Text = 'Plot color';
            app.PlotcolorDropDown = uidropdown(app.IndividualNeuronAnalysisTab);
            app.PlotcolorDropDown.Items = {'Black_', 'Blue__', 'Red___', 'Green_', 'Orange', 'Purple', 'Pink__'};
            app.PlotcolorDropDown.Value = 'Black_';
            app.GroupComparisonsTab = uitab(app.TabGroup);
            app.GroupComparisonsTab.Title = 'Group Comparisons';
            app.PlasticityGroupComparisonsLabel = uilabel(app.GroupComparisonsTab);
            app.PlasticityGroupComparisonsLabel.Text = 'Plasticity Group Comparisons';
            app.ExpgrponeCheckBox = uicheckbox(app.GroupComparisonsTab);
            app.ExpgrponeCheckBox.Text = 'Exp grp one';
            app.ExpgrponeCheckBox.Value = true;
            app.ExpgrptwoCheckBox = uicheckbox(app.GroupComparisonsTab);
            app.ExpgrptwoCheckBox.Text = 'Exp grp two';
            app.ExpgrptwoCheckBox.Value = true;
            app.ExpgrpthreeCheckBox = uicheckbox(app.GroupComparisonsTab);
            app.ExpgrpthreeCheckBox.Text = 'Exp grp three';
            app.Grp1EditFieldLabel = uilabel(app.GroupComparisonsTab);
            app.Grp1EditFieldLabel.Text = 'Grp 1:';
            app.Grp1EditField = uieditfield(app.GroupComparisonsTab, 'text');
            app.Grp1EditField.Value = 'Wildtype';
            app.Grp2EditFieldLabel = uilabel(app.GroupComparisonsTab);
            app.Grp2EditFieldLabel.Text = 'Grp 2:';
            app.Grp2EditField = uieditfield(app.GroupComparisonsTab, 'text');
            app.Grp3EditFieldLabel = uilabel(app.GroupComparisonsTab);
            app.Grp3EditFieldLabel.Text = 'Grp 3:';
            app.Grp3EditField = uieditfield(app.GroupComparisonsTab, 'text');
            app.GrponeplotcolorDropDownLabel = uilabel(app.GroupComparisonsTab);
            app.GrponeplotcolorDropDownLabel.Text = 'Grp one plot color';
            app.GrponeplotcolorDropDown = uidropdown(app.GroupComparisonsTab);
            app.GrponeplotcolorDropDown.Items = {'Black_', 'Blue__', 'Red___', 'Green_', 'Orange', 'Purple', 'Pink__'};
            app.GrponeplotcolorDropDown.Value = 'Black_';
            app.GrptwoplotcolorDropDownLabel = uilabel(app.GroupComparisonsTab);
            app.GrptwoplotcolorDropDownLabel.Text = 'Grp two plot color';
            app.GrptwoplotcolorDropDown = uidropdown(app.GroupComparisonsTab);
            app.GrptwoplotcolorDropDown.Items = {'Black_', 'Blue__', 'Red___', 'Green_', 'Orange', 'Purple', 'Pink__'};
            app.GrptwoplotcolorDropDown.Value = 'Black_';
            app.GrpthreeplotcolorDropDownLabel = uilabel(app.GroupComparisonsTab);
            app.GrpthreeplotcolorDropDownLabel.Text = 'Grp three plot color';
            app.GrpthreeplotcolorDropDown = uidropdown(app.GroupComparisonsTab);
            app.GrpthreeplotcolorDropDown.Items = {'Black_', 'Blue__', 'Red___', 'Green_', 'Orange', 'Purple', 'Pink__'};
            app.GrpthreeplotcolorDropDown.Value = 'Black_';
            app.SaveButton = uibutton(app.UIFigure, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.PickFolderButton = uibutton(app.UIFigure, 'push');
            app.PickFolderButton.ButtonPushedFcn = createCallbackFcn(app, @PickFolderButtonPushed, true);
            app.PickFolderButton.Text = 'Pick Folder';
            app.FolderTBDLabel = uilabel(app.UIFigure);
            app.FolderTBDLabel.Text = 'Folder TBD';
            app.RunButton = uibutton(app.UIFigure, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'Run';
            app.StimulusstartsecEditFieldLabel = uilabel(app.UIFigure);
            app.StimulusstartsecEditFieldLabel.Text = 'Stimulus start (sec)';
            app.StimulusstartsecEditField = uieditfield(app.UIFigure, 'numeric');
            app.StimulusstartsecEditField.Limits = [0 Inf];
            app.StimulusstartsecEditField.ValueDisplayFormat = '%11.10g';
            app.StimulusstartsecEditField.Value = 10.7343;
            app.SamplerateHzEditFieldLabel = uilabel(app.UIFigure);
            app.SamplerateHzEditFieldLabel.Text = 'Sample rate (Hz)';
            app.SamplerateHzEditField = uieditfield(app.UIFigure, 'numeric');
            app.SamplerateHzEditField.Limits = [0 Inf];
            app.SamplerateHzEditField.ValueDisplayFormat = '%11.10g';
            app.SamplerateHzEditField.Value = 10000;
            app.StimperminEditFieldLabel = uilabel(app.UIFigure);
            app.StimperminEditFieldLabel.Text = 'Stim. per min.';
            app.StimperminEditField = uieditfield(app.UIFigure, 'numeric');
            app.StimperminEditField.Value = 4;
            app.AnalysisdurationminEditFieldLabel = uilabel(app.UIFigure);
            app.AnalysisdurationminEditFieldLabel.Text = 'Analysis duration (min)';
            app.AnalysisdurationminEditField = uieditfield(app.UIFigure, 'numeric');
            app.AnalysisdurationminEditField.Value = 5;
        end
    end

    methods (Access = public)

        % Construct app
        function app = plasticitySuite

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end