classdef sponCurrentSuite < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        PickFolderButton                matlab.ui.control.Button
        RunButton                       matlab.ui.control.Button
        SpontaneousPSCAnalysisLabel     matlab.ui.control.Label
        FolderTBDLabel                  matlab.ui.control.Label
        SaveButton                      matlab.ui.control.Button
        TabGroup                        matlab.ui.container.TabGroup
        IndividualNeuronAnalysisTab     matlab.ui.container.Tab
        InitialParamsLabel              matlab.ui.control.Label
        DetectionParamsLabel            matlab.ui.control.Label
        SamplerateHzEditFieldLabel      matlab.ui.control.Label
        SamplerateHzEditField           matlab.ui.control.NumericEditField
        RainjectionstartmsEditFieldLabel  matlab.ui.control.Label
        RainjectionstartmsEditField     matlab.ui.control.NumericEditField
        CurrentSwitchLabel              matlab.ui.control.Label
        CurrentSwitch                   matlab.ui.control.RockerSwitch
        RainjectionendmsEditFieldLabel  matlab.ui.control.Label
        RainjectionendmsEditField       matlab.ui.control.NumericEditField
        LengthofsweepsecEditFieldLabel  matlab.ui.control.Label
        LengthofsweepsecEditField       matlab.ui.control.NumericEditField
        NoiseSDEditFieldLabel           matlab.ui.control.Label
        NoiseSDEditField                matlab.ui.control.NumericEditField
        TemplateFactorEditFieldLabel    matlab.ui.control.Label
        TemplateFactorEditField         matlab.ui.control.NumericEditField
        NumberofsweepsEditFieldLabel    matlab.ui.control.Label
        NumberofsweepsEditField         matlab.ui.control.NumericEditField
        PlotcolorDropDownLabel          matlab.ui.control.Label
        PlotcolorDropDown               matlab.ui.control.DropDown
        GroupComparisonsTab             matlab.ui.container.Tab
        GrponeplotcolorDropDown         matlab.ui.control.DropDown
        GrponeplotcolorDropDownLabel    matlab.ui.control.Label
        ExpgrpthreeCheckBox             matlab.ui.control.CheckBox
        Grp3EditFieldLabel              matlab.ui.control.Label
        Grp3EditField                   matlab.ui.control.EditField
        ExCellEditFieldLabel            matlab.ui.control.Label
        ExCellEditField                 matlab.ui.control.NumericEditField
        ExpgrptwoCheckBox               matlab.ui.control.CheckBox
        Grp2EditFieldLabel              matlab.ui.control.Label
        Grp2EditField                   matlab.ui.control.EditField
        ExCellEditField_2Label          matlab.ui.control.Label
        ExCellEditField_2               matlab.ui.control.NumericEditField
        ExpgrponeCheckBox               matlab.ui.control.CheckBox
        Grp1EditFieldLabel              matlab.ui.control.Label
        Grp1EditField                   matlab.ui.control.EditField
        ExCellEditField_3Label          matlab.ui.control.Label
        ExCellEditField_3               matlab.ui.control.NumericEditField
        GrptwoplotcolorDropDown         matlab.ui.control.DropDown
        GrptwoplotcolorDropDownLabel    matlab.ui.control.Label
        GrpthreeplotcolorDropDown       matlab.ui.control.DropDown
        GrpthreeplotcolorDropDownLabel  matlab.ui.control.Label
        FolderNameEditFieldLabel        matlab.ui.control.Label
        FolderNameEditField             matlab.ui.control.EditField
        SpontaneousEventComparisonsLabel  matlab.ui.control.Label
        SamplerateHzEditField_2Label    matlab.ui.control.Label
        SamplerateHzEditField_2         matlab.ui.control.NumericEditField
    end

    
    properties (Access = public)
        spscDir % folder director
        spscFiles % subfolders, if necessary
        spscData % Description
        spscCompData %GROUP comparison data
        plotColor %colors
        plotColorGrps %colors for group comparisons
    end
    

    methods (Access = private)

        % Button pushed function: PickFolderButton
        function PickFolderButtonPushed(app, event)
            clear app.spscDir app.fldrs fSPSC
            
            if strcmp(app.TabGroup.SelectedTab.Title,'Individual Neuron Analysis')
                app.spscDir=uigetdir;
                thisPt = find(app.spscDir=='/');
                app.FolderTBDLabel.Text = app.spscDir(thisPt(end)+1:end);
            elseif strcmp(app.TabGroup.SelectedTab.Title,'Group Comparisons')
                app.spscDir=uigetdir;
                thisPt = find(app.spscDir=='/');
                app.FolderTBDLabel.Text = app.spscDir(thisPt(end)+1:end);
            end
            
        end

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)


            % function here
            if strcmp(app.TabGroup.SelectedTab.Title,'Individual Neuron Analysis')
                
                % get color
                app.plotColor = zeros(11,3);
                if app.PlotcolorDropDown.Value == 'Black_'
                    app.plotColor(:,1) = 0:.9/10:.9;
                    app.plotColor(:,2) = 0:.9/10:.9;
                    app.plotColor(:,3) = 0:.9/10:.9;
                elseif app.PlotcolorDropDown.Value == 'Blue__'
                    app.plotColor(:,1) = .0625:(.3333-.0625)/10:.3333;
                    app.plotColor(:,2) = .2545:(.8039-.2545)/10:.8039;
                    app.plotColor(:,3) = .433:(1-.433)/10:1;
                elseif app.PlotcolorDropDown.Value == 'Red___'
                    app.plotColor(:,1) = .3922:(.8824-.3922)/10:.8824;
                    app.plotColor(:,2) = 0;
                    app.plotColor(:,3) = 0;
                elseif app.PlotcolorDropDown.Value == 'Green_'
                    app.plotColor(:,1) = .35:(.5833-.35)/10:.5833;
                    app.plotColor(:,2) = .6:(1-.6)/10:1;
                    app.plotColor(:,3) = .25:(.4167-.25)/10:.4167;
                elseif app.PlotcolorDropDown.Value == 'Orange'
                    app.plotColor(:,1) = .85:(1-.85)/10:1;
                    app.plotColor(:,2) = .35:(.6-.35)/10:.6;
                    app.plotColor(:,3) = .1:(.3-.1)/10:.3;
                elseif app.PlotcolorDropDown.Value == 'Purple'
                    app.plotColor(:,1) = .6:(1-.6)/10:1;
                    app.plotColor(:,2) = .4:(.67-.4)/10:.67;
                    app.plotColor(:,3) = .67:(1-.67)/10:1;
                elseif app.PlotcolorDropDown.Value == 'Pink__'
                    app.plotColor(:,1) = .67:(1-.67)/10:1;
                    app.plotColor(:,2) = .2:(.655-.2)/10:.655;
                    app.plotColor(:,3) = .25:(.72-.25)/10:.72;
                end
                
                app.spscData = sponPSCFxn(app);
            elseif strcmp(app.TabGroup.SelectedTab.Title,'Group Comparisons')
                
                % get color
                app.plotColorGrps = zeros(3,3);
                if strcmp(app.GrponeplotcolorDropDown.Value,'Black')
                    app.plotColorGrps(1,:) = [0 0 0];
                elseif strcmp(app.GrponeplotcolorDropDown.Value,'Blue')
                    app.plotColorGrps(1,:) = [.3333 .8039 .9882];
                elseif strcmp(app.GrponeplotcolorDropDown.Value,'Red')
                    app.plotColorGrps(1,:) = [.3922 0 0];
                elseif strcmp(app.GrponeplotcolorDropDown.Value,'Green')
                    app.plotColorGrps(1,:) = [.35 .6 .25];
                elseif strcmp(app.GrponeplotcolorDropDown.Value,'Orange')
                    app.plotColorGrps(1,:) = [.85 .35 .1];
                elseif strcmp(app.GrponeplotcolorDropDown.Value,'Purple')
                    app.plotColorGrps(1,:) = [.6 .4 .67];
                elseif strcmp(app.GrponeplotcolorDropDown.Value,'Pink')
                    app.plotColorGrps(1,:) = [.9686 .6588 .7216];
                end
                
                if strcmp(app.GrptwoplotcolorDropDown.Value,'Black')
                    app.plotColorGrps(2,:) = [0 0 0];
                elseif strcmp(app.GrptwoplotcolorDropDown.Value,'Blue')
                    app.plotColorGrps(2,:) = [.3333 .8039 .9882];
                elseif strcmp(app.GrptwoplotcolorDropDown.Value,'Red')
                    app.plotColorGrps(2,:) = [.3922 0 0];
                elseif strcmp(app.GrptwoplotcolorDropDown.Value,'Green')
                    app.plotColorGrps(2,:) = [.35 .6 .25];
                elseif strcmp(app.GrptwoplotcolorDropDown.Value,'Orange')
                    app.plotColorGrps(2,:) = [.85 .35 .1];
                elseif strcmp(app.GrptwoplotcolorDropDown.Value,'Purple')
                    app.plotColorGrps(2,:) = [.6 .4 .67];
                elseif strcmp(app.GrptwoplotcolorDropDown.Value,'Pink')
                    app.plotColorGrps(2,:) = [.9686 .6588 .7216];
                end
                
                if strcmp(app.GrpthreeplotcolorDropDown.Value,'Black')
                    app.plotColorGrps(3,:) = [0 0 0];
                elseif strcmp(app.GrpthreeplotcolorDropDown.Value,'Blue')
                    app.plotColorGrps(3,:) = [.3333 .8039 .9882];
                elseif strcmp(app.GrpthreeplotcolorDropDown.Value,'Red')
                    app.plotColorGrps(3,:) = [.3922 0 0];
                elseif strcmp(app.GrpthreeplotcolorDropDown.Value,'Green')
                    app.plotColorGrps(3,:) = [.35 .6 .25];
                elseif strcmp(app.GrpthreeplotcolorDropDown.Value,'Orange')
                    app.plotColorGrps(3,:) = [.85 .35 .1];
                elseif strcmp(app.GrpthreeplotcolorDropDown.Value,'Purple')
                    app.plotColorGrps(3,:) = [.6 .4 .67];
                elseif strcmp(app.GrpthreeplotcolorDropDown.Value,'Pink')
                    app.plotColorGrps(3,:) = [.9686 .6588 .7216];
                end
                
                app.spscCompData = seCompFxn(app);
            end
            
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            clear save_file path_save
            [save_file,path_save]=uiputfile;
            full_save_file=fullfile(path_save,save_file);
            save(full_save_file);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.PickFolderButton = uibutton(app.UIFigure, 'push');
            app.PickFolderButton.ButtonPushedFcn = createCallbackFcn(app, @PickFolderButtonPushed, true);
            app.PickFolderButton.Text = 'Pick Folder';
            app.RunButton = uibutton(app.UIFigure, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'Run';
            app.SpontaneousPSCAnalysisLabel = uilabel(app.UIFigure);
            app.SpontaneousPSCAnalysisLabel.Text = 'Spontaneous PSC Analysis';
            app.FolderTBDLabel = uilabel(app.UIFigure);
            app.FolderTBDLabel.Text = 'Folder TBD';
            app.SaveButton = uibutton(app.UIFigure, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.IndividualNeuronAnalysisTab = uitab(app.TabGroup);
            app.IndividualNeuronAnalysisTab.Title = 'Individual Neuron Analysis';
            app.InitialParamsLabel = uilabel(app.IndividualNeuronAnalysisTab);
            app.InitialParamsLabel.Text = 'Initial Params';
            app.DetectionParamsLabel = uilabel(app.IndividualNeuronAnalysisTab);
            app.DetectionParamsLabel.Text = 'Detection Params';
            app.SamplerateHzEditFieldLabel = uilabel(app.IndividualNeuronAnalysisTab);
            app.SamplerateHzEditFieldLabel.Text = 'Sample rate (Hz)';
            app.SamplerateHzEditField = uieditfield(app.IndividualNeuronAnalysisTab, 'numeric');
            app.SamplerateHzEditField.Limits = [0 Inf];
            app.SamplerateHzEditField.ValueDisplayFormat = '%11.10g';
            app.SamplerateHzEditField.Value = 10000;
            app.RainjectionstartmsEditFieldLabel = uilabel(app.IndividualNeuronAnalysisTab);
            app.RainjectionstartmsEditFieldLabel.Text = 'Ra injection start (ms)';
            app.RainjectionstartmsEditField = uieditfield(app.IndividualNeuronAnalysisTab, 'numeric');
            app.RainjectionstartmsEditField.Limits = [0 Inf];
            app.RainjectionstartmsEditField.ValueDisplayFormat = '%11.10g';
            app.RainjectionstartmsEditField.Value = 812.7;
            app.CurrentSwitchLabel = uilabel(app.IndividualNeuronAnalysisTab);
            app.CurrentSwitchLabel.Text = 'Current';
            app.CurrentSwitch = uiswitch(app.IndividualNeuronAnalysisTab, 'rocker');
            app.CurrentSwitch.Items = {'IPSC', 'EPSC'};
            app.CurrentSwitch.Value = 'IPSC';
            app.RainjectionendmsEditFieldLabel = uilabel(app.IndividualNeuronAnalysisTab);
            app.RainjectionendmsEditFieldLabel.Text = 'Ra injection end (ms)';
            app.RainjectionendmsEditField = uieditfield(app.IndividualNeuronAnalysisTab, 'numeric');
            app.RainjectionendmsEditField.Limits = [0 Inf];
            app.RainjectionendmsEditField.ValueDisplayFormat = '%11.10g';
            app.RainjectionendmsEditField.Value = 912.6;
            app.LengthofsweepsecEditFieldLabel = uilabel(app.IndividualNeuronAnalysisTab);
            app.LengthofsweepsecEditFieldLabel.Text = 'Length of sweep (sec)';
            app.LengthofsweepsecEditField = uieditfield(app.IndividualNeuronAnalysisTab, 'numeric');
            app.LengthofsweepsecEditField.Limits = [0 Inf];
            app.LengthofsweepsecEditField.ValueDisplayFormat = '%11.10g';
            app.LengthofsweepsecEditField.Value = 20;
            app.NoiseSDEditFieldLabel = uilabel(app.IndividualNeuronAnalysisTab);
            app.NoiseSDEditFieldLabel.Text = 'Noise SD';
            app.NoiseSDEditField = uieditfield(app.IndividualNeuronAnalysisTab, 'numeric');
            app.NoiseSDEditField.Limits = [0 Inf];
            app.NoiseSDEditField.ValueDisplayFormat = '%11.10g';
            app.NoiseSDEditField.Value = 5;
            app.TemplateFactorEditFieldLabel = uilabel(app.IndividualNeuronAnalysisTab);
            app.TemplateFactorEditFieldLabel.Text = 'Template Factor';
            app.TemplateFactorEditField = uieditfield(app.IndividualNeuronAnalysisTab, 'numeric');
            app.TemplateFactorEditField.Limits = [0 Inf];
            app.TemplateFactorEditField.ValueDisplayFormat = '%11.10g';
            app.TemplateFactorEditField.Value = 10;
            app.NumberofsweepsEditFieldLabel = uilabel(app.IndividualNeuronAnalysisTab);
            app.NumberofsweepsEditFieldLabel.Text = 'Number of sweeps';
            app.NumberofsweepsEditField = uieditfield(app.IndividualNeuronAnalysisTab, 'numeric');
            app.NumberofsweepsEditField.Limits = [0 Inf];
            app.NumberofsweepsEditField.ValueDisplayFormat = '%11.10g';
            app.NumberofsweepsEditField.Value = 15;
            app.PlotcolorDropDownLabel = uilabel(app.IndividualNeuronAnalysisTab);
            app.PlotcolorDropDownLabel.Text = 'Plot color';
            app.PlotcolorDropDown = uidropdown(app.IndividualNeuronAnalysisTab);
            app.PlotcolorDropDown.Items = {'Black_', 'Blue__', 'Red___', 'Green_', 'Orange', 'Purple', 'Pink__'};
            app.PlotcolorDropDown.Value = 'Black_';
            app.GroupComparisonsTab = uitab(app.TabGroup);
            app.GroupComparisonsTab.Title = 'Group Comparisons';
            app.GrponeplotcolorDropDown = uidropdown(app.GroupComparisonsTab);
            app.GrponeplotcolorDropDown.Items = {'Black', 'Blue', 'Red', 'Green', 'Orange', 'Purple', 'Pink'};
            app.GrponeplotcolorDropDown.Value = 'Green';
            app.GrponeplotcolorDropDownLabel = uilabel(app.GroupComparisonsTab);
            app.GrponeplotcolorDropDownLabel.Text = 'Grp one plot color';
            app.ExpgrpthreeCheckBox = uicheckbox(app.GroupComparisonsTab);
            app.ExpgrpthreeCheckBox.Text = 'Exp grp three';
            app.Grp3EditFieldLabel = uilabel(app.GroupComparisonsTab);
            app.Grp3EditFieldLabel.Text = 'Grp 3:';
            app.Grp3EditField = uieditfield(app.GroupComparisonsTab, 'text');
            app.ExCellEditFieldLabel = uilabel(app.GroupComparisonsTab);
            app.ExCellEditFieldLabel.Text = 'Ex. Cell';
            app.ExCellEditField = uieditfield(app.GroupComparisonsTab, 'numeric');
            app.ExCellEditField.Value = 1;
            app.ExpgrptwoCheckBox = uicheckbox(app.GroupComparisonsTab);
            app.ExpgrptwoCheckBox.Text = 'Exp grp two';
            app.ExpgrptwoCheckBox.Value = true;
            app.Grp2EditFieldLabel = uilabel(app.GroupComparisonsTab);
            app.Grp2EditFieldLabel.Text = 'Grp 2:';
            app.Grp2EditField = uieditfield(app.GroupComparisonsTab, 'text');
            app.ExCellEditField_2Label = uilabel(app.GroupComparisonsTab);
            app.ExCellEditField_2Label.Text = 'Ex. Cell';
            app.ExCellEditField_2 = uieditfield(app.GroupComparisonsTab, 'numeric');
            app.ExCellEditField_2.Value = 1;
            app.ExpgrponeCheckBox = uicheckbox(app.GroupComparisonsTab);
            app.ExpgrponeCheckBox.Text = 'Exp grp one';
            app.ExpgrponeCheckBox.Value = true;
            app.Grp1EditFieldLabel = uilabel(app.GroupComparisonsTab);
            app.Grp1EditFieldLabel.Text = 'Grp 1:';
            app.Grp1EditField = uieditfield(app.GroupComparisonsTab, 'text');
            app.Grp1EditField.Value = 'PV-tdTomato';
            app.ExCellEditField_3Label = uilabel(app.GroupComparisonsTab);
            app.ExCellEditField_3Label.Text = 'Ex. Cell';
            app.ExCellEditField_3 = uieditfield(app.GroupComparisonsTab, 'numeric');
            app.ExCellEditField_3.Value = 1;
            app.GrptwoplotcolorDropDown = uidropdown(app.GroupComparisonsTab);
            app.GrptwoplotcolorDropDown.Items = {'Black', 'Blue', 'Red', 'Green', 'Orange', 'Purple', 'Pink'};
            app.GrptwoplotcolorDropDown.Value = 'Purple';
            app.GrptwoplotcolorDropDownLabel = uilabel(app.GroupComparisonsTab);
            app.GrptwoplotcolorDropDownLabel.Text = 'Grp two plot color';
            app.GrpthreeplotcolorDropDown = uidropdown(app.GroupComparisonsTab);
            app.GrpthreeplotcolorDropDown.Items = {'Black', 'Blue', 'Red', 'Green', 'Orange', 'Purple', 'Pink'};
            app.GrpthreeplotcolorDropDown.Value = 'Pink';
            app.GrpthreeplotcolorDropDownLabel = uilabel(app.GroupComparisonsTab);
            app.GrpthreeplotcolorDropDownLabel.Text = 'Grp three plot color';
            app.FolderNameEditFieldLabel = uilabel(app.GroupComparisonsTab);
            app.FolderNameEditFieldLabel.Text = 'Folder Name';
            app.FolderNameEditField = uieditfield(app.GroupComparisonsTab, 'text');
            app.FolderNameEditField.Value = 'sEPSC';
            app.SpontaneousEventComparisonsLabel = uilabel(app.GroupComparisonsTab);
            app.SpontaneousEventComparisonsLabel.Text = 'Spontaneous Event Comparisons';
            app.SamplerateHzEditField_2Label = uilabel(app.GroupComparisonsTab);
            app.SamplerateHzEditField_2Label.Text = 'Sample rate (Hz)';
            app.SamplerateHzEditField_2 = uieditfield(app.GroupComparisonsTab, 'numeric');
            app.SamplerateHzEditField_2.Limits = [0 Inf];
            app.SamplerateHzEditField_2.ValueDisplayFormat = '%11.10g';
            app.SamplerateHzEditField_2.Value = 10000;
        end
    end

    methods (Access = public)

        % Construct app
        function app = sponCurrentSuite

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end