classdef unitarySuite < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        PickFolderButton                matlab.ui.control.Button
        RunButton                       matlab.ui.control.Button
        SpontaneousPSCAnalysisLabel     matlab.ui.control.Label
        FolderTBDLabel                  matlab.ui.control.Label
        SaveButton                      matlab.ui.control.Button
        TabGroup                        matlab.ui.container.TabGroup
        IndividualNeuronAnalysisTab     matlab.ui.container.Tab
        InitialParamsLabel              matlab.ui.control.Label
        SamplerateHzEditFieldLabel      matlab.ui.control.Label
        SamplerateHzEditField           matlab.ui.control.NumericEditField
        RainjectionstartmsEditFieldLabel  matlab.ui.control.Label
        RainjectionstartmsEditField     matlab.ui.control.NumericEditField
        CurrentSwitchLabel              matlab.ui.control.Label
        CurrentSwitch                   matlab.ui.control.RockerSwitch
        RainjectionendmsEditFieldLabel  matlab.ui.control.Label
        RainjectionendmsEditField       matlab.ui.control.NumericEditField
        LengthofsweepsecEditFieldLabel  matlab.ui.control.Label
        LengthofsweepsecEditField       matlab.ui.control.NumericEditField
        NumberofsweepsEditFieldLabel    matlab.ui.control.Label
        NumberofsweepsEditField         matlab.ui.control.NumericEditField
        PlotcolorDropDownLabel          matlab.ui.control.Label
        PlotcolorDropDown               matlab.ui.control.DropDown
        RecordingChannelDropDownLabel   matlab.ui.control.Label
        RecordingChannelDropDown        matlab.ui.control.DropDown
        UnitaryEventAnalysisLabel       matlab.ui.control.Label
        GroupComparisonsTab             matlab.ui.container.Tab
        Grp3EditFieldLabel              matlab.ui.control.Label
        Grp3EditField                   matlab.ui.control.EditField
        ExpgrponeCheckBox               matlab.ui.control.CheckBox
        ExpgrptwoCheckBox               matlab.ui.control.CheckBox
        ExpgrpthreeCheckBox             matlab.ui.control.CheckBox
        Grp1EditFieldLabel              matlab.ui.control.Label
        Grp1EditField                   matlab.ui.control.EditField
        Grp2EditFieldLabel              matlab.ui.control.Label
        Grp2EditField                   matlab.ui.control.EditField
        ExCellEditFieldLabel            matlab.ui.control.Label
        ExCellEditField                 matlab.ui.control.NumericEditField
        ExCellEditField_2Label          matlab.ui.control.Label
        ExCellEditField_2               matlab.ui.control.NumericEditField
        GrponeplotcolorDropDown         matlab.ui.control.DropDown
        GrponeplotcolorDropDownLabel    matlab.ui.control.Label
        ExCellEditField_3Label          matlab.ui.control.Label
        ExCellEditField_3               matlab.ui.control.NumericEditField
        UnitaryEventAnalysisLabel_2     matlab.ui.control.Label
        GrptwoplotcolorDropDown         matlab.ui.control.DropDown
        GrptwoplotcolorDropDownLabel    matlab.ui.control.Label
        GrpthreeplotcolorDropDown       matlab.ui.control.DropDown
        GrpthreeplotcolorDropDownLabel  matlab.ui.control.Label
        FolderNameEditFieldLabel        matlab.ui.control.Label
        FolderNameEditField             matlab.ui.control.EditField
    end


    properties (Access = public)
        upscDir % folder director
        upscFiles % subfolders, if necessary
        upscData % Description
        upscCompData %GROUP comparison data
        plotColor %colors
        plotColorGrps %colors for group comparisons
    end


    methods (Access = private)

        % Button pushed function: PickFolderButton
        function PickFolderButtonPushed(app, event)
            clear app.spscDir app.fldrs fSPSC

            if strcmp(app.TabGroup.SelectedTab.Title,'Individual Neuron Analysis')
                app.upscDir=uigetdir;
                thisPt = find(app.upscDir=='/');
                app.FolderTBDLabel.Text = app.upscDir(thisPt(end)+1:end);
            elseif strcmp(app.TabGroup.SelectedTab.Title,'Group Comparisons')
                app.upscDir=uigetdir;
                thisPt = find(app.upscDir=='/');
                app.FolderTBDLabel.Text = app.upscDir(thisPt(end)+1:end);
            end
                
        end

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
            
            % function here
            if strcmp(app.TabGroup.SelectedTab.Title,'Individual Neuron Analysis')
                
                % get color
                app.plotColor = zeros(1,3);
                if strcmp(app.PlotcolorDropDown.Value,'Black')
                    app.plotColor = [0 0 0];
                elseif strcmp(app.PlotcolorDropDown.Value,'Blue')
                    app.plotColor = [.3333 .8039 .9882];
                elseif strcmp(app.PlotcolorDropDown.Value,'Red')
                    app.plotColor = [.3922 0 0];
                elseif strcmp(app.PlotcolorDropDown.Value,'Green')
                    app.plotColor = [.35 .6 .25];
                elseif strcmp(app.PlotcolorDropDown.Value,'Orange')
                    app.plotColor = [.85 .35 .1];
                elseif strcmp(app.PlotcolorDropDown.Value,'Purple')
                    app.plotColor = [.6 .4 .67];
                elseif strcmp(app.PlotcolorDropDown.Value,'Pink')
                    app.plotColor = [.9686 .6588 .7216];
                end
                
                app.upscData = upscFxn(app);
                
            elseif strcmp(app.TabGroup.SelectedTab.Title,'Group Comparisons')
                % get color
                app.plotColorGrps = zeros(3,3);
                if strcmp(app.GrponeplotcolorDropDown.Value,'Black')
                    app.plotColorGrps(1,:) = [0 0 0];
                elseif strcmp(app.GrponeplotcolorDropDown.Value,'Blue')
                    app.plotColorGrps(1,:) = [.3333 .8039 .9882];
                elseif strcmp(app.GrponeplotcolorDropDown.Value,'Red')
                    app.plotColorGrps(1,:) = [.3922 0 0];
                elseif strcmp(app.GrponeplotcolorDropDown.Value,'Green')
                    app.plotColorGrps(1,:) = [.35 .6 .25];
                elseif strcmp(app.GrponeplotcolorDropDown.Value,'Orange')
                    app.plotColorGrps(1,:) = [.85 .35 .1];
                elseif strcmp(app.GrponeplotcolorDropDown.Value,'Purple')
                    app.plotColorGrps(1,:) = [.6 .4 .67];
                elseif strcmp(app.GrponeplotcolorDropDown.Value,'Pink')
                    app.plotColorGrps(1,:) = [.9686 .6588 .7216];
                end
                
                if strcmp(app.GrptwoplotcolorDropDown.Value,'Black')
                    app.plotColorGrps(2,:) = [0 0 0];
                elseif strcmp(app.GrptwoplotcolorDropDown.Value,'Blue')
                    app.plotColorGrps(2,:) = [.3333 .8039 .9882];
                elseif strcmp(app.GrptwoplotcolorDropDown.Value,'Red')
                    app.plotColorGrps(2,:) = [.3922 0 0];
                elseif strcmp(app.GrptwoplotcolorDropDown.Value,'Green')
                    app.plotColorGrps(2,:) = [.35 .6 .25];
                elseif strcmp(app.GrptwoplotcolorDropDown.Value,'Orange')
                    app.plotColorGrps(2,:) = [.85 .35 .1];
                elseif strcmp(app.GrptwoplotcolorDropDown.Value,'Purple')
                    app.plotColorGrps(2,:) = [.6 .4 .67];
                elseif strcmp(app.GrptwoplotcolorDropDown.Value,'Pink')
                    app.plotColorGrps(2,:) = [.9686 .6588 .7216];
                end
                
                if strcmp(app.GrpthreeplotcolorDropDown.Value,'Black')
                    app.plotColorGrps(3,:) = [0 0 0];
                elseif strcmp(app.GrpthreeplotcolorDropDown.Value,'Blue')
                    app.plotColorGrps(3,:) = [.3333 .8039 .9882];
                elseif strcmp(app.GrpthreeplotcolorDropDown.Value,'Red')
                    app.plotColorGrps(3,:) = [.3922 0 0];
                elseif strcmp(app.GrpthreeplotcolorDropDown.Value,'Green')
                    app.plotColorGrps(3,:) = [.35 .6 .25];
                elseif strcmp(app.GrpthreeplotcolorDropDown.Value,'Orange')
                    app.plotColorGrps(3,:) = [.85 .35 .1];
                elseif strcmp(app.GrpthreeplotcolorDropDown.Value,'Purple')
                    app.plotColorGrps(3,:) = [.6 .4 .67];
                elseif strcmp(app.GrpthreeplotcolorDropDown.Value,'Pink')
                    app.plotColorGrps(3,:) = [.9686 .6588 .7216];
                end
                
                app.upscCompData = unitaryCompFxn(app);
            end
            
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            clear save_file path_save
            [save_file,path_save]=uiputfile;
            full_save_file=fullfile(path_save,save_file);
            save(full_save_file);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.PickFolderButton = uibutton(app.UIFigure, 'push');
            app.PickFolderButton.ButtonPushedFcn = createCallbackFcn(app, @PickFolderButtonPushed, true);
            app.PickFolderButton.Text = 'Pick Folder';
            app.RunButton = uibutton(app.UIFigure, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'Run';
            app.SpontaneousPSCAnalysisLabel = uilabel(app.UIFigure);
            app.SpontaneousPSCAnalysisLabel.Text = 'Spontaneous PSC Analysis';
            app.FolderTBDLabel = uilabel(app.UIFigure);
            app.FolderTBDLabel.Text = 'Folder TBD';
            app.SaveButton = uibutton(app.UIFigure, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.IndividualNeuronAnalysisTab = uitab(app.TabGroup);
            app.IndividualNeuronAnalysisTab.Title = 'Individual Neuron Analysis';
            app.InitialParamsLabel = uilabel(app.IndividualNeuronAnalysisTab);
            app.InitialParamsLabel.Text = 'Initial Params';
            app.SamplerateHzEditFieldLabel = uilabel(app.IndividualNeuronAnalysisTab);
            app.SamplerateHzEditFieldLabel.Text = 'Sample rate (Hz)';
            app.SamplerateHzEditField = uieditfield(app.IndividualNeuronAnalysisTab, 'numeric');
            app.SamplerateHzEditField.Limits = [0 Inf];
            app.SamplerateHzEditField.ValueDisplayFormat = '%11.10g';
            app.SamplerateHzEditField.Value = 10000;
            app.RainjectionstartmsEditFieldLabel = uilabel(app.IndividualNeuronAnalysisTab);
            app.RainjectionstartmsEditFieldLabel.Text = 'Ra injection start (ms)';
            app.RainjectionstartmsEditField = uieditfield(app.IndividualNeuronAnalysisTab, 'numeric');
            app.RainjectionstartmsEditField.Limits = [0 Inf];
            app.RainjectionstartmsEditField.ValueDisplayFormat = '%11.10g';
            app.RainjectionstartmsEditField.Value = 562.7;
            app.CurrentSwitchLabel = uilabel(app.IndividualNeuronAnalysisTab);
            app.CurrentSwitchLabel.Text = 'Current';
            app.CurrentSwitch = uiswitch(app.IndividualNeuronAnalysisTab, 'rocker');
            app.CurrentSwitch.Items = {'IPSC', 'EPSC'};
            app.CurrentSwitch.Value = 'EPSC';
            app.RainjectionendmsEditFieldLabel = uilabel(app.IndividualNeuronAnalysisTab);
            app.RainjectionendmsEditFieldLabel.Text = 'Ra injection end (ms)';
            app.RainjectionendmsEditField = uieditfield(app.IndividualNeuronAnalysisTab, 'numeric');
            app.RainjectionendmsEditField.Limits = [0 Inf];
            app.RainjectionendmsEditField.ValueDisplayFormat = '%11.10g';
            app.RainjectionendmsEditField.Value = 662.6;
            app.LengthofsweepsecEditFieldLabel = uilabel(app.IndividualNeuronAnalysisTab);
            app.LengthofsweepsecEditFieldLabel.Text = 'Length of sweep (sec)';
            app.LengthofsweepsecEditField = uieditfield(app.IndividualNeuronAnalysisTab, 'numeric');
            app.LengthofsweepsecEditField.Limits = [0 Inf];
            app.LengthofsweepsecEditField.ValueDisplayFormat = '%11.10g';
            app.LengthofsweepsecEditField.Value = 4;
            app.NumberofsweepsEditFieldLabel = uilabel(app.IndividualNeuronAnalysisTab);
            app.NumberofsweepsEditFieldLabel.Text = 'Number of sweeps';
            app.NumberofsweepsEditField = uieditfield(app.IndividualNeuronAnalysisTab, 'numeric');
            app.NumberofsweepsEditField.Limits = [0 Inf];
            app.NumberofsweepsEditField.ValueDisplayFormat = '%11.10g';
            app.NumberofsweepsEditField.Value = 30;
            app.PlotcolorDropDownLabel = uilabel(app.IndividualNeuronAnalysisTab);
            app.PlotcolorDropDownLabel.Text = 'Plot color';
            app.PlotcolorDropDown = uidropdown(app.IndividualNeuronAnalysisTab);
            app.PlotcolorDropDown.Items = {'Black', 'Blue', 'Red', 'Green', 'Orange', 'Purple', 'Pink'};
            app.PlotcolorDropDown.Value = 'Black';
            app.RecordingChannelDropDownLabel = uilabel(app.IndividualNeuronAnalysisTab);
            app.RecordingChannelDropDownLabel.Text = 'Recording Channel';
            app.RecordingChannelDropDown = uidropdown(app.IndividualNeuronAnalysisTab);
            app.RecordingChannelDropDown.Items = {'1', '2'};
            app.RecordingChannelDropDown.Value = '1';
            app.UnitaryEventAnalysisLabel = uilabel(app.IndividualNeuronAnalysisTab);
            app.UnitaryEventAnalysisLabel.Text = 'Unitary Event Analysis';
            app.GroupComparisonsTab = uitab(app.TabGroup);
            app.GroupComparisonsTab.Title = 'Group Comparisons';
            app.Grp3EditFieldLabel = uilabel(app.GroupComparisonsTab);
            app.Grp3EditFieldLabel.Text = 'Grp 3:';
            app.Grp3EditField = uieditfield(app.GroupComparisonsTab, 'text');
            app.ExpgrponeCheckBox = uicheckbox(app.GroupComparisonsTab);
            app.ExpgrponeCheckBox.Text = 'Exp grp one';
            app.ExpgrponeCheckBox.Value = true;
            app.ExpgrptwoCheckBox = uicheckbox(app.GroupComparisonsTab);
            app.ExpgrptwoCheckBox.Text = 'Exp grp two';
            app.ExpgrptwoCheckBox.Value = true;
            app.ExpgrpthreeCheckBox = uicheckbox(app.GroupComparisonsTab);
            app.ExpgrpthreeCheckBox.Text = 'Exp grp three';
            app.Grp1EditFieldLabel = uilabel(app.GroupComparisonsTab);
            app.Grp1EditFieldLabel.Text = 'Grp 1:';
            app.Grp1EditField = uieditfield(app.GroupComparisonsTab, 'text');
            app.Grp2EditFieldLabel = uilabel(app.GroupComparisonsTab);
            app.Grp2EditFieldLabel.Text = 'Grp 2:';
            app.Grp2EditField = uieditfield(app.GroupComparisonsTab, 'text');
            app.ExCellEditFieldLabel = uilabel(app.GroupComparisonsTab);
            app.ExCellEditFieldLabel.Text = 'Ex. Cell';
            app.ExCellEditField = uieditfield(app.GroupComparisonsTab, 'numeric');
            app.ExCellEditField.Value = 1;
            app.ExCellEditField_2Label = uilabel(app.GroupComparisonsTab);
            app.ExCellEditField_2Label.Text = 'Ex. Cell';
            app.ExCellEditField_2 = uieditfield(app.GroupComparisonsTab, 'numeric');
            app.ExCellEditField_2.Value = 1;
            app.GrponeplotcolorDropDown = uidropdown(app.GroupComparisonsTab);
            app.GrponeplotcolorDropDown.Items = {'Black', 'Blue', 'Red', 'Green', 'Orange', 'Purple', 'Pink'};
            app.GrponeplotcolorDropDown.Value = 'Black';
            app.GrponeplotcolorDropDownLabel = uilabel(app.GroupComparisonsTab);
            app.GrponeplotcolorDropDownLabel.Text = 'Grp one plot color';
            app.ExCellEditField_3Label = uilabel(app.GroupComparisonsTab);
            app.ExCellEditField_3Label.Text = 'Ex. Cell';
            app.ExCellEditField_3 = uieditfield(app.GroupComparisonsTab, 'numeric');
            app.ExCellEditField_3.Value = 1;
            app.UnitaryEventAnalysisLabel_2 = uilabel(app.GroupComparisonsTab);
            app.UnitaryEventAnalysisLabel_2.Text = 'Unitary Event Analysis';
            app.GrptwoplotcolorDropDown = uidropdown(app.GroupComparisonsTab);
            app.GrptwoplotcolorDropDown.Items = {'Black', 'Blue', 'Red', 'Green', 'Orange', 'Purple', 'Pink'};
            app.GrptwoplotcolorDropDown.Value = 'Black';
            app.GrptwoplotcolorDropDownLabel = uilabel(app.GroupComparisonsTab);
            app.GrptwoplotcolorDropDownLabel.Text = 'Grp two plot color';
            app.GrpthreeplotcolorDropDown = uidropdown(app.GroupComparisonsTab);
            app.GrpthreeplotcolorDropDown.Items = {'Black', 'Blue', 'Red', 'Green', 'Orange', 'Purple', 'Pink'};
            app.GrpthreeplotcolorDropDown.Value = 'Black';
            app.GrpthreeplotcolorDropDownLabel = uilabel(app.GroupComparisonsTab);
            app.GrpthreeplotcolorDropDownLabel.Text = 'Grp three plot color';
            app.FolderNameEditFieldLabel = uilabel(app.GroupComparisonsTab);
            app.FolderNameEditFieldLabel.Text = 'Folder Name';
            app.FolderNameEditField = uieditfield(app.GroupComparisonsTab, 'text');
            app.FolderNameEditField.Value = 'PNIN';
        end
    end

    methods (Access = public)

        % Construct app
        function app = unitarySuite

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end